classdef Para_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VodnpraUIFigure       matlab.ui.Figure
        DjPanel               matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        etaEditField          matlab.ui.control.NumericEditField
        etaLabel              matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        GridLayout_3          matlab.ui.container.GridLayout
        woEditFieldLabel      matlab.ui.control.Label
        woEditField           matlab.ui.control.NumericEditField
        wtEditFieldLabel      matlab.ui.control.Label
        wtEditField           matlab.ui.control.NumericEditField
        sdlenteploLabel       matlab.ui.control.Label
        objemovprceLabel      matlab.ui.control.Label
        tlakovprceLabel       matlab.ui.control.Label
        dd_q                  matlab.ui.control.DropDown
        dd_wo                 matlab.ui.control.DropDown
        dd_wt                 matlab.ui.control.DropDown
        qEditField            matlab.ui.control.NumericEditField
        qEditFieldLabel       matlab.ui.control.Label
        Panel_3               matlab.ui.container.Panel
        diagramDropDownLabel  matlab.ui.control.Label
        diagramDropDown       matlab.ui.control.DropDown
        UIAxes                matlab.ui.control.UIAxes
        Panel_5               matlab.ui.container.Panel
        PotenstavPanel        matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        tEditField_3Label     matlab.ui.control.Label
        tEditField            matlab.ui.control.NumericEditField
        pEditField_3Label     matlab.ui.control.Label
        pEditField            matlab.ui.control.NumericEditField
        vEditField_3Label     matlab.ui.control.Label
        vEditField            matlab.ui.control.NumericEditField
        hEditField_3Label     matlab.ui.control.Label
        hEditField            matlab.ui.control.NumericEditField
        uEditField_3Label     matlab.ui.control.Label
        uEditField            matlab.ui.control.NumericEditField
        sEditField_3Label     matlab.ui.control.Label
        sEditField            matlab.ui.control.NumericEditField
        xEditField_3Label     matlab.ui.control.Label
        xEditField            matlab.ui.control.NumericEditField
        dd_p_p                matlab.ui.control.DropDown
        teplotaLabel          matlab.ui.control.Label
        tlakLabel             matlab.ui.control.Label
        objemLabel            matlab.ui.control.Label
        entalpieLabel         matlab.ui.control.Label
        energieLabel          matlab.ui.control.Label
        entropieLabel         matlab.ui.control.Label
        suchostLabel          matlab.ui.control.Label
        dd_p_t                matlab.ui.control.DropDown
        dd_p_v                matlab.ui.control.DropDown
        dd_p_h                matlab.ui.control.DropDown
        dd_p_u                matlab.ui.control.DropDown
        dd_p_s                matlab.ui.control.DropDown
        dd_p_x                matlab.ui.control.DropDown
        HledejButton          matlab.ui.control.Button
        NatiButton            matlab.ui.control.Button
        ZnovupsButton         matlab.ui.control.Button
        Panel_6               matlab.ui.container.Panel
        KoncovstavPanel       matlab.ui.container.Panel
        GridLayout_2          matlab.ui.container.GridLayout
        tEditField_2Label     matlab.ui.control.Label
        tEditField_2          matlab.ui.control.NumericEditField
        pEditField_2Label     matlab.ui.control.Label
        pEditField_2          matlab.ui.control.NumericEditField
        vEditField_2Label     matlab.ui.control.Label
        vEditField_2          matlab.ui.control.NumericEditField
        hEditField_2Label     matlab.ui.control.Label
        hEditField_2          matlab.ui.control.NumericEditField
        uEditField_2Label     matlab.ui.control.Label
        uEditField_2          matlab.ui.control.NumericEditField
        sEditField_2Label     matlab.ui.control.Label
        sEditField_2          matlab.ui.control.NumericEditField
        xEditField_2Label     matlab.ui.control.Label
        xEditField_2          matlab.ui.control.NumericEditField
        dd_d_p                matlab.ui.control.DropDown
        teplotaLabel_2        matlab.ui.control.Label
        tlakLabel_2           matlab.ui.control.Label
        objemLabel_2          matlab.ui.control.Label
        entalpieLabel_2       matlab.ui.control.Label
        energieLabel_2        matlab.ui.control.Label
        entropieLabel_2       matlab.ui.control.Label
        suchostLabel_2        matlab.ui.control.Label
        dd_d_t                matlab.ui.control.DropDown
        dd_d_v                matlab.ui.control.DropDown
        dd_d_h                matlab.ui.control.DropDown
        dd_d_u                matlab.ui.control.DropDown
        dd_d_s                matlab.ui.control.DropDown
        dd_d_x                matlab.ui.control.DropDown
        UloButton_2           matlab.ui.control.Button
        VypotiButton          matlab.ui.control.Button
        ZnovuButton           matlab.ui.control.Button
        VymaButton            matlab.ui.control.Button
        ExitButton            matlab.ui.control.Button
        UloDiagramButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Property % Description
        %prevod_k % struct konstant prevodu jednotek
        
        %hlidaja stav pro reset oken jednotlivych stavu
        hold_stavu 
        %hold_vals
        
        %"stare" konstanty
        konst 
        
        %"paměť" pro převody jednotek
        hold_unit
        
        % plot stuff
        point_marker
        point_marker_size
        linewidth
        
        %hodnoty maximalnich mezi
        %jedna se o bod_class
        hodnoty_minim
        hodnoty_maxim
        hodnoty_minim_neverchange
        hodnoty_maxim_neverchange
        
        %zde je uložená default prázdná hodnota
        isempty_val
        
        % boolean that holds wheather it is safe to calculate "after
        % pushing the button"
        can_calc
        
        % boolean that holds wheather is it safe to push "Posuň" button
        can_move
        
        % boolean that holds wheather vypočti button was pushed
        vypocti_pushed
        ulo_pushed
        
        %memory of points (dej -> bod1)
        mem_deju % obsluhuje načtený děj, meze ploty, obsahuje vždy jen jeden děj
        mem_bodu % paměť bodů ve formě listu. Co děj to dva body
        mem_djvelicin % pameť dějových veličin
        mem_size % velikost pameti (pocet bodu v mem_bodu)
        %memory of highest vaue in specific variable
        %plot stuff -> autozoom
        %default h,t set and used
        znama_maxima
        
        mem_select_app
        last_diagram
        dlg_opts
        vypoti_pushed_flag
        errValue
    end


    methods (Access = private)
        function user_ts_para(app,fig,in,t,p,v,s,x,h,u)
            %       testovaci funkce pro komunikaci s uzivatelem
            %
            %                 disp('zatim umim jen nasledujici')
            %                 disp('h - p = 1')
            %                 disp('h - s = 2')
            %                 disp('t - p = 3')
            %                 disp('h - t = 4')
            %                 disp('s - t = 5')
            %                 disp('s - p = 6')
            %                 disp('t - x = 7')
            %                 disp('p - x = 8')
            %                 disp('h - x = 9')
            %                 disp('s - x = 10')
            %                 disp('t - v = 11')
            %                 disp('p - v = 12')
            %                 disp('h - v = 13')
            %                 disp('s - v = 14')
            %                 disp('x - v = 15')
            barva = app.konst.barvy.iso_plot;
            tloustka = 1;
            switch in
                case 1
                    %h-p
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isoh',h);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isobara',p);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 2
                    %h-s
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isoh',h);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    l2=xlineCustom(app,fig,s,barva,tloustka);
                case 3
                    %t-p
                    l1=ylineCustom(app,2,fig,t,barva,tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isobara',p);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 4
                    %h-t
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isoh',h);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    l2=ylineCustom(app,2,fig,t,barva,tloustka);
                case 5
                    %s-t
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    l2=ylineCustom(app,2,fig,t,barva,tloustka);
                case 6
                    %s-p
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isobara',p);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 7
                    %t-x
                    l1=ylineCustom(app,2,fig,t,barva,tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 8
                    %p-x
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isobara',p);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 9
                    %h-x
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isoh',h);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 10
                    %s-x
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 11
                    %t-v
                    l1=ylineCustom(app,2,fig,t,barva,tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isochora',v);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 12
                    %p-v
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isobara',p);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isochora',v);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 13
                    %h-v
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isoh',h);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isochora',v);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 14
                    %s-v
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isochora',v);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 15
                    %x-v
                    [userT,userS] = vypocetTS(app.konst.konstTS,'such',x);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetTS(app.konst.konstTS,'isochora',v);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 16 % 16 t-u
                    
                case 17 % 17 p-u
                case 18 % 18 h-u
                case 19 % 19 s-u
                case 20 % 20 v-u
                case 21 % 21 x-u
                otherwise
            end
            %alpha(l2,.5)
            %alpha(l1,.5)
            %nefunguje
            %uistack(l1,'bottom')
            %uistack(l2,'bottom')
            
        end
        function user_hs_para(app,fig,in,t,p,v,s,x,h,u) %[t,p,v,s,x,h,u]=user_hs_para(~,fig,in)
            %testovaci funkce pro komunikaci s uzivatelem
            %input parameter je odkaz na figuru (diagram)
            %
            %     disp('zatim umim jen nasledujici')
            %     disp('h - p = 1')
            %     disp('h - s = 2')
            %     disp('t - p = 3')
            %     disp('h - t = 4')
            %     disp('s - t = 5')
            %     disp('s - p = 6')
            %     disp('t - x = 7')
            %     disp('p - x = 8')
            %     disp('h - x = 9')
            %     disp('s - x = 10')
            %     disp('t - v = 11')
            %     disp('p - v = 12')
            %     disp('h - v = 13')
            %     disp('s - v = 14')
            %     disp('x - v = 15')
            %
            barva = app.konst.barvy.iso_plot;
            tloustka = 1;
            switch in
                case 1
                    %h-p
                    l1=ylineCustom(app,1,fig,h,barva,tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isobara',p);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 2
                    %h-s
                    l1=ylineCustom(app,1,fig,h,barva,tloustka);
                    
                    l2=xlineCustom(app,fig,s,barva,tloustka);
                case 3
                    %t-p
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isoterma',t);
                    l1=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isobara',p);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 4
                    %h-t
                    l1=ylineCustom(app,1,fig,h,barva,tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isoterma',t);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 5
                    %s-t
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isoterma',t);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 6
                    %s-p
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    [userT,userS] = vypocetHS(app.konst.konstHS,'isobara',p);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 7
                    %t-x
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isoterma',t);
                    l1=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetHS(app.konst.konstHS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 8
                    %p-x
                    [userT,userS] = vypocetHS(app.konst.konstHS,'isobara',p);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetHS(app.konst.konstHS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 9
                    %h-x
                    l1=ylineCustom(app,1,fig,h,barva,tloustka);
                    
                    [userT,userS] = vypocetHS(app.konst.konstHS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 10
                    %s-x
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    [userT,userS] = vypocetHS(app.konst.konstHS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 11
                    %t-v
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isoterma',t);
                    l1=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isochora',v);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 12
                    %p-v
                    [userT,userS] = vypocetHS(app.konst.konstHS,'isobara',p);
                    l1=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isochora',v);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 13
                    %h-v
                    l1=ylineCustom(app,1,fig,h,barva,tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isochora',v);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 14
                    %s-v
                    l1=xlineCustom(app,fig,s,barva,tloustka);
                    
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isochora',v);
                    l2=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                case 15
                    %x-v
                    [userH,userS] = vypocetHS(app.konst.konstHS,'isochora',v);
                    l1=plot(fig,userS,userH,'color',barva,'LineWidth',tloustka);
                    
                    [userT,userS] = vypocetHS(app.konst.konstHS,'such',x);
                    l2=plot(fig,userS,userT,'color',barva,'LineWidth',tloustka);
                case 16 % 16 t-u
                    
                case 17 % 17 p-u
                case 18 % 18 h-u
                case 19 % 19 s-u
                case 20 % 20 v-u
                case 21 % 21 x-u
                otherwise
                    %in=0;
                    warning("unknown input")
            end
            %alpha(l2,.5)
            %alpha(l1,.5)
        end
%         function ret = get_unit_val_p(~,unit)
%             ret = -100;
%             switch unit
%                 case 'bar'
%                     ret = 0;
%                 case 'Pa'
%                     ret = -5;
%                 case 'kPa'
%                     ret = -2;
%                 case 'MPa'
%                     ret = 1;
%             end
%         end
%         function ret = get_unit_val_v(~,unit)
%             ret = -100;
%             switch unit
%                 case 'dm3/kg'
%                     ret = 3;
%                 case 'm3/kg'
%                     ret = 0;
%             end
%         end
%         function ret = get_unit_val_uh(~,unit)
%             ret = -100;
%             switch unit
%                 case 'J/kg'
%                     ret = 3;
%                 case 'kJ/kg'
%                     ret = 0;
%             end
%         end
%         function ret = get_unit_val_s(~,unit)
%             ret = inf;
%             switch unit
%                 case 'J/kg/K'
%                     ret = 3;
%                 case 'kJ/kg/K'
%                     ret = 0;
%             end
%         end
%         function ret = get_unit_val_t(~,unit)
%             ret = inf;
%             switch unit
%                 case 'K'
%                     ret = 1;
%                 case '°C'
%                     ret = 0;
%             end
%         end
%         function results = prevod_(app,mam_val,mam_unit,typ,chci_unit)
%             results = -1;
%             %             mam_unit = app.hold_unit.unit;
%             %             mam_val = app.hold_unit.val;
%             switch typ
%                 case 't'
%                     mam = get_unit_val_t(app,mam_unit);
%                     chci = get_unit_val_t(app,chci_unit);
%                     diff = mam - chci;
%                     if diff == 0 %neprevadim
%                         results = mam_val;
%                     elseif diff == 1 %
%                         results = mam_val - 273.15;
%                     else %diff == -1 %
%                         results = mam_val + 273.15;
%                     end
%                     
%                 case 'p'
%                     mam = get_unit_val_p(app,mam_unit);
%                     chci = get_unit_val_p(app,chci_unit);
%                     diff = mam - chci;
%                     results = mam_val * 10^diff;
%                 case 'v'
%                     mam = get_unit_val_v(app,mam_unit);
%                     chci = get_unit_val_v(app,chci_unit);
%                     diff = chci - mam;
%                     results = mam_val * 10^diff;
%                 case 'h'
%                     mam = get_unit_val_uh(app,mam_unit);
%                     chci = get_unit_val_uh(app,chci_unit);
%                     diff = chci - mam;
%                     results = mam_val * 10^diff;
%                 case 'u'
%                     mam = get_unit_val_uh(app,mam_unit);
%                     chci = get_unit_val_uh(app,chci_unit);
%                     diff = chci - mam;
%                     results = mam_val * 10^diff;
%                 case 's'
%                     mam = get_unit_val_s(app,mam_unit);
%                     chci = get_unit_val_s(app,chci_unit);
%                     diff = chci - mam;
%                     results = mam_val * 10^diff;
%                 case 'x'
%                     results = mam_val;
%             end
%         end
        %         function results = prevod_out(app,val,typ)
        %             if typ == 't'
        %                 results = val - app.prevod_k.t;
        %             elseif typ == 'p'
        %                 results = val / app.prevod_k.p;
        %             elseif typ == 'v'
        %                 results = val / app.prevod_k.v;
        %             elseif typ == 'h'
        %                 results = val / app.prevod_k.h;
        %             elseif typ == 'u'
        %                 results = val / app.prevod_k.u;
        %             elseif typ == 's'
        %                 results = val / app.prevod_k.s;
        %             end
        %         end
        %         function results = prevod_in(app,val,typ)
        %             if typ == 't'
        %                 results = val + app.prevod_k.t;
        %             elseif typ == 'p'
        %                 results = val * app.prevod_k.p;
        %             elseif typ == 'v'
        %                 results = val * app.prevod_k.v;
        %             elseif typ == 'h'
        %                 results = val * app.prevod_k.h;
        %             elseif typ == 'u'
        %                 results = val * app.prevod_k.u;
        %             elseif typ == 's'
        %                 results = val * app.prevod_k.s;
        %             end
        %         end

        
        function add_state(app,pripad,t,p,v,s,x,h,u)
            %point_marker = 'r*';
            diagram1 = app.UIAxes;
            hold(diagram1, 'on')
            diagram_dd_1 = app.diagramDropDown.Value;

            switch diagram_dd_1
                case 'T-s'
                    %plot pomocnych car
                    %user_ts_para(app,diagram1,pripad,t,p,v,s,x,h,u)
                    %plot bodi
                    plot(diagram1,s,t,app.point_marker)
                case 'h-s'
                    %user_hs_para(app,diagram1,pripad,t,p,v,s,x,h,u)
                    plot(diagram1,s,h,app.point_marker)
                case 'p-v'
            end

            hold(diagram1,'off')
            hold(diagram1,'on')
        end
        function clear_pocatecniStav_p(app)
            app.tEditField.Value=app.isempty_val;
            app.pEditField.Value=app.isempty_val;
            app.vEditField.Value=app.isempty_val;
            app.hEditField.Value=app.isempty_val;
            app.uEditField.Value=app.isempty_val;
            app.sEditField.Value=app.isempty_val;
            app.xEditField.Value=app.isempty_val;
            
            app.hold_stavu.poc = 0;
            
                        %reset fontWeights
            app.tEditField.FontWeight = 'normal';
            app.pEditField.FontWeight = 'normal';
            app.vEditField.FontWeight = 'normal';
            app.hEditField.FontWeight = 'normal';
            app.sEditField.FontWeight = 'normal';
            app.xEditField.FontWeight = 'normal';
            app.uEditField.FontWeight = 'normal';
        end
        function clear_pocatecniStav_d(app)
            %zero edit fields
            app.tEditField_2.Value=app.isempty_val;
            app.pEditField_2.Value=app.isempty_val;
            app.vEditField_2.Value=app.isempty_val;
            app.hEditField_2.Value=app.isempty_val;
            app.uEditField_2.Value=app.isempty_val;
            app.sEditField_2.Value=app.isempty_val;
            app.xEditField_2.Value=app.isempty_val;
            %and make proper ones editable
            typ = app.DropDown.Value;
            unlock_d(app)
            lock_d(app,typ)
                        %reset fontWeights
            app.tEditField_2.FontWeight = 'normal';
            app.pEditField_2.FontWeight = 'normal';
            app.vEditField_2.FontWeight = 'normal';
            app.hEditField_2.FontWeight = 'normal';
            app.sEditField_2.FontWeight = 'normal';
            app.xEditField_2.FontWeight = 'normal';
            app.uEditField_2.FontWeight = 'normal';
        end

        
        function [pripad] = zjisti_pripad_zadani(app)
            
            t=app.tEditField.Value;
            p=app.pEditField.Value;
            v=app.vEditField.Value;
            h=app.hEditField.Value;
            u=app.uEditField.Value;
            s=app.sEditField.Value;
            x=app.xEditField.Value;
            
            pripad = -1;
            if t ~= app.isempty_val
                if p ~= app.isempty_val
                    pripad = 3;
                elseif x ~= app.isempty_val
                    pripad = 7;
                elseif v ~= app.isempty_val
                    pripad = 11;
                end
                %return
            end
            if p ~= app.isempty_val
                if x ~= app.isempty_val
                    pripad = 8;
                elseif v ~= app.isempty_val
                    pripad = 12;
                end
                %return
            end
            if v ~= app.isempty_val
                if x ~= app.isempty_val
                    pripad = 15;
                end
                %return
            end
            if h ~= app.isempty_val
                if p ~= app.isempty_val
                    pripad = 1;
                elseif s ~= app.isempty_val
                    pripad = 2;
                elseif t ~= app.isempty_val
                    pripad = 4;
                elseif x ~= app.isempty_val
                    pripad = 9;
                elseif v  ~= app.isempty_val
                    pripad = 13;
                end
                %return
            end
            
            if s ~= app.isempty_val
                if t ~= app.isempty_val
                    pripad = 5;
                elseif p ~= app.isempty_val
                    pripad = 6;
                elseif x ~= app.isempty_val
                    pripad = 10;
                elseif v ~= app.isempty_val
                    pripad = 14;
                end
                %return
            end
            if u ~= app.isempty_val
                if t ~= app.isempty_val
                    pripad = 16;
                elseif p ~= app.isempty_val
                    pripad = 17;
                elseif h ~= app.isempty_val
                    pripad = 18;
                elseif s ~= app.isempty_val
                    pripad = 19;
                elseif v ~= app.isempty_val
                    pripad = 20;
                elseif x ~= app.isempty_val
                    pripad = 21;
                    
                end
                %to do
                return
            end
            
        end
        function [v1,v2,v1_unit,v2_unit] = zjisti_vstupni_hodnoty_poc(app,stav)
            switch stav
                case 1
                    v1 = app.hEditField.Value;
                    v2 = app.pEditField.Value;
                    v1_unit = app.dd_p_h.Value;
                    v2_unit = app.dd_p_p.Value;
                case 2
                    v1 = app.hEditField.Value;
                    v2 = app.sEditField.Value;
                    v1_unit = app.dd_p_h.Value;
                    v2_unit = app.dd_p_s.Value;
                case 3
                    v1 = app.tEditField.Value;
                    v2 = app.pEditField.Value;
                    v1_unit = app.dd_p_t.Value;
                    v2_unit = app.dd_p_p.Value;
                case 4
                    v1 = app.hEditField.Value;
                    v2 = app.tEditField.Value;
                    v1_unit = app.dd_p_h.Value;
                    v2_unit = app.dd_p_t.Value;
                case 5
                    v1 = app.sEditField.Value;
                    v2 = app.tEditField.Value;
                    v1_unit = app.dd_p_s.Value;
                    v2_unit = app.dd_p_t.Value;
                case 6
                    v1 = app.sEditField.Value;
                    v2 = app.pEditField.Value;
                    v1_unit = app.dd_p_s.Value;
                    v2_unit = app.dd_p_p.Value;
                case 7
                    v1 = app.tEditField.Value;
                    v2 = app.xEditField.Value;
                    v1_unit = app.dd_p_t.Value;
                    v2_unit = app.dd_p_x.Value;
                case 8
                    v1 = app.pEditField.Value;
                    v2 = app.xEditField.Value;
                    v1_unit = app.dd_p_p.Value;
                    v2_unit = app.dd_p_x.Value;
                case 9
                    v1 = app.hEditField.Value;
                    v2 = app.xEditField.Value;
                    v1_unit = app.dd_p_h.Value;
                    v2_unit = app.dd_p_x.Value;
                case 10
                    v1 = app.sEditField.Value;
                    v2 = app.xEditField.Value;
                    v1_unit = app.dd_p_s.Value;
                    v2_unit = app.dd_p_x.Value;
                case 11
                    v1 = app.tEditField.Value;
                    v2 = app.vEditField.Value;
                    v1_unit = app.dd_p_t.Value;
                    v2_unit = app.dd_p_v.Value;
                case 12
                    v1 = app.pEditField.Value;
                    v2 = app.vEditField.Value;
                    v1_unit = app.dd_p_p.Value;
                    v2_unit = app.dd_p_v.Value;
                case 13
                    v1 = app.hEditField.Value;
                    v2 = app.vEditField.Value;
                    v1_unit = app.dd_p_h.Value;
                    v2_unit = app.dd_p_v.Value;
                case 14
                    v1 = app.sEditField.Value;
                    v2 = app.vEditField.Value;
                    v1_unit = app.dd_p_s.Value;
                    v2_unit = app.dd_p_v.Value;
                case 15
                    v1 = app.xEditField.Value;
                    v2 = app.vEditField.Value;
                    v1_unit = app.dd_p_x.Value;
                    v2_unit = app.dd_p_v.Value;
                case 16 % 16 t-u
                    v1 = app.tEditField.Value;
                    v2 = app.uEditField.Value;
                    v1_unit = app.dd_p_t.Value;
                    v2_unit = app.dd_p_u.Value;
                case 17 % 17 p-u
                    v1 = app.pEditField.Value;
                    v2 = app.uEditField.Value;
                    v1_unit = app.dd_p_p.Value;
                    v2_unit = app.dd_p_u.Value;
                case 18 % 18 h-u
                    v1 = app.hEditField.Value;
                    v2 = app.uEditField.Value;
                    v1_unit = app.dd_p_h.Value;
                    v2_unit = app.dd_p_u.Value;
                case 19 % 19 s-u
                    v1 = app.sEditField.Value;
                    v2 = app.uEditField.Value;
                    v1_unit = app.dd_p_s.Value;
                    v2_unit = app.dd_p_u.Value;
                case 20 % 20 v-u
                    v1 = app.vEditField.Value;
                    v2 = app.uEditField.Value;
                    v1_unit = app.dd_p_v.Value;
                    v2_unit = app.dd_p_u.Value;
                case 21 % 21 x-u
                    v1 = app.xEditField.Value;
                    v2 = app.uEditField.Value;
                    v1_unit = app.dd_p_x.Value;
                    v2_unit = app.dd_p_u.Value;
                case -1
                    
            end
        end
        
        function typ = zjisti_pripad_deje(app,dej,t2,p2,v2,h2,u2,s2,x2)
            %             case "izobarický"
            %                 dej = 1; => p
            %             case "izotermický"
            %                 dej = 2; => t
            %             case "izochorický"
            %                 dej = 3; => v
            % 1 h-p
            % 2 h-s
            % 3 t-p
            % 4 h-t
            % 5 s-t
            % 6 s-p
            % 7 t-x
            % 8 p-x
            % 9 h-x
            % 10 s-x
            % 11 t-v
            % 12 p-v
            % 13 h-v
            % 14 s-v
            % 15 x-v
            % 16 t-u
            % 17 p-u
            % 18 h-u
            % 19 s-u
            % 20 v-u
            % 21 x-u
            %
            if dej == 5 %adia
                dej = 4; %pocitam to jako s
            end
            if dej == 1 %p
                if t2~=app.isempty_val%t-p
                    typ = 3;
                elseif p2~=app.isempty_val%p-p ??
                    typ = -1;
                elseif v2~=app.isempty_val%p-v
                    typ = 12;
                elseif h2~=app.isempty_val%h-p
                    typ = 1;
                elseif u2~=app.isempty_val%p-u
                    typ = 17;
                elseif s2~=app.isempty_val%s-p
                    typ = 6;
                elseif x2~=app.isempty_val%p-x
                    typ = 8;
                end
            elseif dej == 2 %t
                if t2~=app.isempty_val%t-t ??
                    typ = -1;
                elseif p2~=app.isempty_val%t-p
                    typ = 3;
                elseif v2~=app.isempty_val%t-v
                    typ = 11;
                elseif h2~=app.isempty_val%h-t
                    typ = 4;
                elseif u2~=app.isempty_val%t-u
                    typ = 16;
                elseif s2~=app.isempty_val%t-s
                    typ = 5;
                elseif x2~=app.isempty_val%t-x
                    typ = 7;
                end
                % 11 t-v
                % 12 p-v
                % 13 h-v
                % 14 s-v
                % 15 x-v
                % 20 v-u
                % 21 x-u
            elseif dej == 3 %v
                if t2~=app.isempty_val%t-v
                    typ = 11;
                elseif p2~=app.isempty_val%p-v
                    typ = 12;
                elseif v2~=app.isempty_val%v-v
                    typ = -1;
                elseif h2~=app.isempty_val%h-v
                    typ = 13;
                elseif u2~=app.isempty_val%u-v
                    typ = 20;
                elseif s2~=app.isempty_val%s-v
                    typ = 14;
                elseif x2~=app.isempty_val%x-v
                    typ = 15;
                end
            elseif dej == 4 %s
                %Line 2: %2h-s
                %Line 5: %5s-t
                %Line 6: %6s-p
                %Line 10: %10s-x
                %Line 14: %14s-v
                %Line 19: %19s-u
                if t2~=app.isempty_val%s-t
                    typ = 5;
                elseif p2~=app.isempty_val%s-p
                    typ = 6;
                elseif v2~=app.isempty_val%s-v
                    typ = 14;
                elseif h2~=app.isempty_val%h-s
                    typ = 2;
                elseif u2~=app.isempty_val%s-u
                    typ = 19;
                elseif s2~=app.isempty_val%s-s
                    typ = -1;
                elseif x2~=app.isempty_val%s-x
                    typ = 10;
                end
                
            elseif dej == 6 %h
                %   Line 1: %1h-p
                % 	Line 2: %2h-s
                % 	Line 4: %4h-t
                % 	Line 9: %9h-x
                % 	Line 13: %13h-v
                % 	Line 18: %18h-u
                if t2~=app.isempty_val%h-t
                    typ = 4;
                elseif p2~=app.isempty_val%hp
                    typ = 1;
                elseif v2~=app.isempty_val%h-v
                    typ = 13;
                elseif h2~=app.isempty_val%h-h
                    typ = -1;
                elseif u2~=app.isempty_val%h-u
                    typ = 18;
                elseif s2~=app.isempty_val%h-s
                    typ = 2;
                elseif x2~=app.isempty_val%h-x
                    typ = 9;
                end
            end
        end
        
        
        function ukazKoncStav(app,t,p,v,h,s,x,u)
            if isnan(t)
                t=-1;
            end
            if isnan(p)
                p=-1;
            end
            if isnan(v)
                v=-1;
            end
            if isnan(s)
                s=-1;
            end
            if isnan(x)
                x = -1;
            end
            if isnan(h)
                h=-1;
            end
            if isnan(u)
                u=-1;
            end
            %vyplň políčka v sekci koncový stav
            app.tEditField_2.Value=prevod_(app,t,'°C','t',app.dd_d_t.Value);%t+app.prevod_k.t;
            app.pEditField_2.Value=prevod_(app,p,'bar','p',app.dd_d_p.Value);%p/app.prevod_k.p;
            app.vEditField_2.Value=prevod_(app,v,'m3/kg','v',app.dd_d_v.Value);%v/app.prevod_k.v;
            app.hEditField_2.Value=prevod_(app,h,'kJ/kg','h',app.dd_d_h.Value);%h/app.prevod_k.h;
            app.uEditField_2.Value=prevod_(app,u,'kJ/kg','u',app.dd_d_u.Value);%u/app.prevod_k.u;
            app.sEditField_2.Value=prevod_(app,s,'kJ/kg/K','s',app.dd_d_s.Value);%s/app.prevod_k.s;
            app.xEditField_2.Value=x;
        end
        
%         function line1 = plotDeje(app,outH_hs,outS_hs,outT_ts,outS_ts)
%             % get plot type
%             if strcmp(app.diagramDropDown.Value,'h-s')
%                 x1=outS_hs;
%                 y1=outH_hs;
%             elseif strcmp(app.diagramDropDown.Value,'T-s')
%                 x1=outS_ts;
%                 y1=outT_ts;
%             elseif strcmp(app.diagramDropDown.Value,'p-v')
%                 %todo
%                 warning("todo pv")
%             end
%             % get plot line color
%             dej = app.DropDown.Value;
%             switch dej
%                 case 'izobarický' 
%                     barva = app.konst.barvy.izobaricky;
%                 case 'izotermický'
%                     barva = app.konst.barvy.izotermicky;
%                 case 'izochorický'
%                     barva = app.konst.barvy.izochoricky; 
%                 case 'izoentropický'
%                     barva = app.konst.barvy.izoentropicky; 
%                 case 'adiabatický'
%                     barva = app.konst.barvy.adiabaticky; 
%                 case 'izoentalpický'
%                     barva = app.konst.barvy.izoentalpicky; 
%             end
%             line1 = plot(app.UIAxes,x1,y1,"LineWidth",app.linewidth, "Color", barva);
%         end
        
        function ret = xlineCustom(app,fig,value,barva,tloustka)
            %xlineCustom(app,fig,s,barva,tloustka);
            
            if fig == app.UIAxes
                pripad = app.diagramDropDown.Value;

            end
            switch pripad
                case 'T-s'
                    y=[0,800];
                case 'h-s'
                    y=[0,4200];
                otherwise
                    error('no pv just yet')
            end
            %y=[0,800];
            x=[value,value];
            ret = plot(fig,x,y,'color',barva,'LineWidth',tloustka);
            %ret = plot(fig,0,0);
        end
        function ret = ylineCustom(~,~,fig,value,barva,tloustka)
            %xlineCustom(app,fig,s,barva,tloustka);
            %             switch pripad
            %                 case 1
            %                     x=[0,9];
            %                 case 2
            %                     x=[0,10];
            %                 otherwise
            %                     x=[0,10];
            %             end
            x=[0,10];
            y=[value,value];
            ret = plot(fig,x,y,'color',barva,'LineWidth',tloustka);
            %ret = plot(fig,0,0);
        end
        
        function [t,p,v,h,u,s,x] = get_poc_stav_vals(app)
            %lizni si stav 1
            t=app.tEditField.Value;
            p=app.pEditField.Value;
            v=app.vEditField.Value;
            h=app.hEditField.Value;
            u=app.uEditField.Value;
            s=app.sEditField.Value;
            x=app.xEditField.Value;
            t_unit=app.dd_p_t.Value;
            p_unit=app.dd_p_p.Value;
            v_unit=app.dd_p_v.Value;
            h_unit=app.dd_p_h.Value;
            u_unit=app.dd_p_u.Value;
            s_unit=app.dd_p_s.Value;
            %x_unit=app.dd_p_x.Value;
            
            %preved na jednotky xsteam-u
            t = prevod_(app,t,t_unit,"t","°C");
            p = prevod_(app,p,p_unit,"p","bar");
            v = prevod_(app,v,v_unit,"v","m3/kg");
            h = prevod_(app,h,h_unit,"h","kJ/kg");
            u = prevod_(app,u,u_unit,"u","kJ/kg");
            s = prevod_(app,s,s_unit,"s","kJ/kg/K");
            %x = prevod_(app,x,t_unit,"","");
        end        
        function [t,p,v,h,u,s,x] = get_konc_stav_vals(app)
            %lizni si stav 1
            t=app.tEditField_2.Value;
            p=app.pEditField_2.Value;
            v=app.vEditField_2.Value;
            h=app.hEditField_2.Value;
            u=app.uEditField_2.Value;
            s=app.sEditField_2.Value;
            x=app.xEditField_2.Value;
            t_unit=app.dd_d_t.Value;
            p_unit=app.dd_d_p.Value;
            v_unit=app.dd_d_v.Value;
            h_unit=app.dd_d_h.Value;
            u_unit=app.dd_d_u.Value;
            s_unit=app.dd_d_s.Value;
            %x_unit=app.dd_p_x.Value;
            
            %preved na jednotky xsteam-u
            t = prevod_(app,t,t_unit,"t","°C");
            p = prevod_(app,p,p_unit,"p","bar");
            v = prevod_(app,v,v_unit,"v","m3/kg");
            h = prevod_(app,h,h_unit,"h","kJ/kg");
            u = prevod_(app,u,u_unit,"u","kJ/kg");
            s = prevod_(app,s,s_unit,"s","kJ/kg/K");
            %x = prevod_(app,x,t_unit,"","");
        end    

        
%         function reset_mezi_p(app)
%             app.tEditField.Limits = [app.hodnoty_minim.t,app.hodnoty_maxim.t];
%             app.pEditField.Limits = [app.hodnoty_minim.p,app.hodnoty_maxim.p];
%             app.vEditField.Limits = [app.hodnoty_minim.v,app.hodnoty_maxim.v];
%             app.hEditField.Limits = [app.hodnoty_minim.h,app.hodnoty_maxim.h];
%             app.uEditField.Limits = [app.hodnoty_minim.u,app.hodnoty_maxim.u];
%             app.sEditField.Limits = [app.hodnoty_minim.s,app.hodnoty_maxim.s];
%             app.xEditField.Limits = [app.hodnoty_minim.x,app.hodnoty_maxim.x];
%         end
%         function reset_mezi_d(app)
%             app.tEditField_2.Limits = [app.hodnoty_minim.t,app.hodnoty_maxim.t];
%             app.pEditField_2.Limits = [app.hodnoty_minim.p,app.hodnoty_maxim.p];
%             app.vEditField_2.Limits = [app.hodnoty_minim.v,app.hodnoty_maxim.v];
%             app.hEditField_2.Limits = [app.hodnoty_minim.h,app.hodnoty_maxim.h];
%             app.uEditField_2.Limits = [app.hodnoty_minim.u,app.hodnoty_maxim.u];
%             app.sEditField_2.Limits = [app.hodnoty_minim.s,app.hodnoty_maxim.s];
%             app.xEditField_2.Limits = [app.hodnoty_minim.x,app.hodnoty_maxim.x];
%         end
        
        function results = kontrola_mezi_vstupu(~,input,minimum,maximum)
            if input >= maximum
                results = maximum;
            elseif input <= minimum
                results = minimum;
            else
                results = input;
            end
        end

        
        function ret = edit_callback(app,var,num,min_val,max_val)
            %num = source.Value;
            ret = 0;
            if app.vypocti_pushed.p == true
                % ks dialog
                if num > max_val || num < min_val
                    if ~strcmp(app.DropDown.Value,'adiabatický')
                        str = ['\fontsize{14}Pro ', app.DropDown.Value,' děj a zadání počátečního stavu v této iplementaci IAPWS IF97 pro ',num2str(var),'platí ',num2str(min_val),' až ',num2str(max_val)];
                        %str = 'Input must be smaller than ';
                        warndlg(str,'Mimo rozsah',app.dlg_opts);
                        ret = 1;
                    end
                end
            else
                % ps dialog
                if num > max_val || num < min_val
                    str = ['\fontsize{14}Rozsah',num2str(var),' této implementace IAPWS IF97 je ',num2str(min_val),' až ',num2str(max_val)];
                    %str = 'Input must be smaller than ';
                    warndlg(str,'Mimo rozsah',app.dlg_opts);
                    ret = 1;
                end
                
            end
            %if second input field is "ok" -> allow button push
%             if app.hold_stavu.poc >= 2
%                 app.can_calc.p = true;
%             end
        end
%             app.tEditField
%             app.pEditField
%             app.vEditField
%             app.hEditField
%             app.sEditField
%             app.xEditField
        function lock_p(app)
            %makes "upper" edit fields non-interactable
            app.tEditField.Editable = 'off';
            app.pEditField.Editable = 'off';
            app.vEditField.Editable = 'off';
            app.hEditField.Editable = 'off';
            app.sEditField.Editable = 'off';
            app.xEditField.Editable = 'off';
            
            app.tEditField.BackgroundColor = [.9 .9 .9];
            app.pEditField.BackgroundColor = [.9 .9 .9];
            app.vEditField.BackgroundColor = [.9 .9 .9];
            app.hEditField.BackgroundColor = [.9 .9 .9];
            app.sEditField.BackgroundColor = [.9 .9 .9];
            app.xEditField.BackgroundColor = [.9 .9 .9];
            
            %and makes Hledej button active
            app.HledejButton.Enable = 'on';
        end
        function unlock_p(app)
            %makes "upper" edit fields interactable
            app.tEditField.Editable = 'on';
            app.pEditField.Editable = 'on';
            app.vEditField.Editable = 'on';
            app.hEditField.Editable = 'on';
            app.sEditField.Editable = 'on';
            app.xEditField.Editable = 'on';
            
            app.tEditField.BackgroundColor = [1 1 1];
            app.pEditField.BackgroundColor = [1 1 1];
            app.vEditField.BackgroundColor = [1 1 1];
            app.hEditField.BackgroundColor = [1 1 1];
            app.sEditField.BackgroundColor = [1 1 1];
            app.xEditField.BackgroundColor = [1 1 1];
            
            app.HledejButton.Enable = 'off';
        end
        function lock_d(app,varargin)
            if nargin==1
                %makes "lower" edit fields non-interactable
                app.tEditField_2.Editable = 'off';
                app.pEditField_2.Editable = 'off';
                app.vEditField_2.Editable = 'off';
                app.hEditField_2.Editable = 'off';
                app.sEditField_2.Editable = 'off';
                app.xEditField_2.Editable = 'off';
                
                app.tEditField_2.BackgroundColor = [.9 .9 .9];
                app.pEditField_2.BackgroundColor = [.9 .9 .9];
                app.vEditField_2.BackgroundColor = [.9 .9 .9];
                app.hEditField_2.BackgroundColor = [.9 .9 .9];
                app.sEditField_2.BackgroundColor = [.9 .9 .9];
                app.xEditField_2.BackgroundColor = [.9 .9 .9];
                
                hide_dj_panel(app);
            elseif nargin ==2
                switch varargin{1}
                    case "izobarický"
                        app.pEditField_2.Editable = 'off';
                        app.pEditField_2.BackgroundColor = [.9 .9 .9];
                    case "izotermický"
                        if app.tEditField.Value >= 373.945
                            %app.xEditField_2.Value = 1;
                            app.xEditField_2.Editable = 'off';
                            app.xEditField_2.BackgroundColor = [.9 .9 .9];
                        end
                        app.tEditField_2.Editable = 'off';
                        app.tEditField_2.BackgroundColor = [.9 .9 .9];
                    case "izochorický"
                        app.vEditField_2.Editable = 'off';
                        app.vEditField_2.BackgroundColor = [.9 .9 .9];
                    case "izoentropický"
                        app.sEditField_2.Editable = 'off';
                        app.sEditField_2.BackgroundColor = [.9 .9 .9];
                    case "adiabatický"
                        app.sEditField_2.Editable = 'off';
                        app.sEditField_2.BackgroundColor = [.9 .9 .9];
                    case "izoentalpický"
                        app.hEditField_2.Editable = 'off';
                        app.hEditField_2.BackgroundColor = [.9 .9 .9];
                end
            else
                error("Too many input arguments!")
            end
            
        end
        function unlock_d(app)
            %makes "lower" edit fields interactable
            app.tEditField_2.Editable = 'on';
            app.pEditField_2.Editable = 'on';
            app.vEditField_2.Editable = 'on';
            app.hEditField_2.Editable = 'on';
            app.sEditField_2.Editable = 'on';
            app.xEditField_2.Editable = 'on';
            
            app.tEditField_2.BackgroundColor = [1 1 1];
            app.pEditField_2.BackgroundColor = [1 1 1];
            app.vEditField_2.BackgroundColor = [1 1 1];
            app.hEditField_2.BackgroundColor = [1 1 1];
            app.sEditField_2.BackgroundColor = [1 1 1];
            app.xEditField_2.BackgroundColor = [1 1 1];
            
%             if app.xEditField_2.Value ~= app.isempty_val
%                 app.xEditField_2.Value = 0;
%             end
        end
        
        function physical_variable_changed(app,value,variable,pos)
            %physical_variable_changed(app,value,min_val,max_val,variable,pos)
            %takes edit field described by variable and pos example variable 'p' pos
            %'h' where p stands for pressure and h stands for upper set of values
            % so it takes 'ph' = app.pEditField.Value
            % valid pos: h,d
            % valid variables t,p,v,h,s,x,u

            [min_val,max_val] = get_limits(app,variable);
                    
            if edit_callback(app,variable,value,min_val,max_val) == 0
                if pos ~= 'd'
                    %přičti si jedničku k počtu zadaných hodnot dle logiky
                    %"je první" -> přičti, "je jiná" -> přičti
                    if isempty(app.hold_stavu.variables)
                        
                        app.hold_stavu.poc = app.hold_stavu.poc + 1;
                        app.hold_stavu.variables = [app.hold_stavu.variables,variable];
                        
                    elseif ~strcmp(app.hold_stavu.variables, variable)
                        
                        app.hold_stavu.poc = app.hold_stavu.poc + 1;
                        app.hold_stavu.variables = [app.hold_stavu.variables,variable];
                    
                    end
                    
                    %a pokud je to druhá hodnota tak zamkni políčka
                    if app.hold_stavu.poc >= 2
                        %this is tooearly for cancalc
                        app.can_calc.p = true;
                        
                        lock_p(app)
                        
                    else
                        %dopočítej platné meze ostatních veličin
                        dej = dej_class;
                        dej = dej.init_dej(app,variable,'h');
                        
                        dej.apply_obj_meze(app);
                        
                    end
                    %boldit h
                    switch variable
                        case 't'
                            app.tEditField.FontWeight = 'bold';
                        case 'p'
                            app.pEditField.FontWeight = 'bold';
                        case 'v'
                            app.vEditField.FontWeight = 'bold';
                        case 'h'
                            app.hEditField.FontWeight = 'bold';
                        case 's'
                            app.sEditField.FontWeight = 'bold';
                        case 'x'
                            app.xEditField.FontWeight = 'bold';
                        case 'u'
                            app.uEditField.FontWeight = 'bold';
                    end
                else
                    %boldit d
                    switch variable
                        case 't'
                            app.tEditField_2.FontWeight = 'bold';
                        case 'p'
                            app.pEditField_2.FontWeight = 'bold';
                        case 'v'
                            app.vEditField_2.FontWeight = 'bold';
                        case 'h'
                            app.hEditField_2.FontWeight = 'bold';
                        case 's'
                            app.sEditField_2.FontWeight = 'bold';
                        case 'x'
                            app.xEditField_2.FontWeight = 'bold';
                        case 'u'
                            app.uEditField_2.FontWeight = 'bold';
                    end
                    app.VypotiButton.Enable = 'on';
                end
            else
                
                commit_single_edit_field(app,[variable,pos],app.isempty_val)
                
            end
        end
        
        function results = translate_typ_deje(app)
            switch app.DropDown.Value
                case "izobarický"
                    results = 'p';
                case "izotermický"
                    results = 't';
                case "izochorický"
                    results = 'v';
                case "izoentropický"
                    results = 's';
                case "adiabatický"
                    results = 's';
                case "izoentalpický"
                    results = 'h';
                otherwise
                    error("unknown DjDropdown.Value")
            end
            
        end
        
        function hide_dj_panel(app)
            app.Panel.Enable = 'off';
            app.Panel.Visible = 'off';
        end
        function show_dj_panel(app)
            app.Panel.Enable = 'on';
            app.Panel.Visible = 'on';
        end
        
%         function [z1, z2] = pripad_to_str(app,pripad)
%             %pripad:
%             % 1 h-p
%             % 2 h-s
%             % 3 t-p
%             % 4 h-t
%             % 5 s-t
%             % 6 s-p
%             % 7 t-x
%             % 8 p-x
%             % 9 h-x
%             % 10 s-x
%             % 11 t-v
%             % 12 p-v
%             % 13 h-v
%             % 14 s-v
%             % 15 x-v
%             % 
%             % 
%             % to do: u
%             z1 = '';
%             z2 = '';
%             switch pripad
%                 case 1
%                     z1 = 'h';
%                     z2 = 'p';
%                 case 2
%                     z1 = 'h';
%                     z2 = 's';
%                 case 3
%                     z1 = 't';
%                     z2 = 'p';
%                 case 4
%                     z1 = 'h';
%                     z2 = 't';
%                 case 5
%                     z1 = 's';
%                     z2 = 't';
%                 case 6
%                     z1 = 's';
%                     z2 = 'p';
%                 case 7
%                     z1 = 't';
%                     z2 = 'x';
%                 case 8
%                     z1 = 'p';
%                     z2 = 'x';
%                 case 9
%                     z1 = 'h';
%                     z2 = 'x';
%                 case 10
%                     z1 = 's';
%                     z2 = 'x';
%                 case 11
%                     z1 = 't';
%                     z2 = 'v';
%                 case 12
%                     z1 = 'p';
%                     z2 = 'v';
%                 case 13
%                     z1 = 'h';
%                     z2 = 'v';
%                 case 14
%                     z1 = 's';
%                     z2 = 'v';
%                 case 15
%                     z1 = 'x';
%                     z2 = 'v';
%             end
%             
%         end
        
        function restoreViewCallback(app,event)
            autozoom(app);
        end
        
        function err_check(app,dj)
            errValue = 0;
            errcount = 0;
            if strcmp(dj,'ps')
               if app.tEditField.Value == -1
                   errcount = errcount + 1;
               end
               if app.pEditField.Value == -1
                   errcount = errcount + 1;
               end
               if app.vEditField.Value == -1
                   errcount = errcount + 1;
               end
               if app.hEditField.Value == -1
                   errcount = errcount + 1;
               end
               if app.sEditField.Value == -1
                   errcount = errcount + 1;
               end
               if app.xEditField.Value == -1
                   errcount = errcount + 1;
               end
               if app.uEditField.Value == -1
                   errcount = errcount + 1;
               end
            elseif strcmp(dj,'ks')
                if app.tEditField_2.Value == -1
                   errcount = errcount + 1;
               end
               if app.pEditField_2.Value == -1
                   errcount = errcount + 1;
               end
               if app.vEditField_2.Value == -1
                   errcount = errcount + 1;
               end
               if app.hEditField_2.Value == -1
                   errcount = errcount + 1;
               end
               if app.sEditField_2.Value == -1
                   errcount = errcount + 1;
               end
               if app.xEditField_2.Value == -1
                   try
                       x = -1;
                       %p = app.pEditField_2.Value;
                       %h = app.hEditField_2.Value;
                       s = app.sEditField_2.Value;
                       if s >= 4.3700
                           if app.tEditField_2 >= 373.945
                               x = 1;
                           end
                       elseif s < 4.3700
                           x = 0;
                       end
%                        if p ~=-1 && h ~=-1 && s ~=-1
%                            x = XSteam('x_ph',p,h);
%                            if isnan(x) || x == -1
%                                x = XSteam('x_ps',p,s);
%                            end
%                        end
                   catch ME
                       x = -1;
                   end
                   if x == -1 || isnan(x)
                       errcount = errcount + 1;
                   else
                       app.xEditField_2.Value = x;
                   end
               end
               if app.uEditField_2.Value == -1
                   errcount = errcount + 1;
               end
            end
            if errcount > 0
                warndlg('\fontsize{14}Něco se nepodařilo spočítat.','Chyba výpočtu',app.dlg_opts)
            end
        end
        
        function checkVol(app)
            % checks volume value for the purposes of calculating
            % isochorický děj. 
            if strcmp(app.DropDown.Value, 'izochorický')
                v = prevod_(app,app.vEditField.Value,app.dd_p_v.Value,'v','m3/kg');
                if v < .002
                    warndlg(['\fontsize{14}Izochorický děj pro v=',num2str(v),'m3/kg nespočítám.',newline(),'Minimální hodnota v=0.002'])
                    app.VypotiButton.Enable = 'off';
                end
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set gui to the center of the screen
            movegui(app.VodnpraUIFigure,"center");
            
            %pridani cest k programu para
            addpath("class","deje\","dopocty_hodnot\","figs\","vypoctove_plot\","XSteam\","MAT2CLIP\");
            
            %nacteni "starych" konstant
            [app.konst.konstHS,app.konst.konstTS,app.konst.barvy] = initKonst;
            %prevod_k je konstanta prevodu jednotek
            %             app.prevod_k.t=0;
            %             app.prevod_k.p=1000;
            %             app.prevod_k.v=1000;
            %             app.prevod_k.h=1;
            %             app.prevod_k.u=1;
            %             app.prevod_k.s=1;
            %app.prevod.x=1;
            
            %inicializace hlidani stavu oken Pocatecni stav a ostatnich
            app.hold_stavu.poc = 0;
            app.hold_stavu.variables = [];
            %inicializace plotu 1 a 2
            %cla(app.UIAxes)
            hold(app.UIAxes,'on');
            fig = openfig('figs\ts_mez.fig','invisible');
            ax = findobj(fig,'Type','Axes');
            copyobj(ax.Children,app.UIAxes);
            hold(app.UIAxes,'on');
            clear fig ax
            % change "home" callback in graph
            % https://www.mathworks.com/matlabcentral/answers/879193-how-can-i-customize-the-callback-of-the-restore-view-built-in-option-on-the-toolstrip-of-a-uiaxes
            % Get the toolbar axes
            axToolstrip = axtoolbar(app.UIAxes, 'default');
            % Get the restore view button and reset the callback function
            isRestoreButton = strcmpi({axToolstrip.Children.Icon}, 'restoreview');
            restoreButtonHandle = axToolstrip.Children(isRestoreButton);
            restoreButtonHandle.ButtonPushedFcn = @(~,ev) restoreViewCallback(app, ev);
            
                        
            %parametry plotu
            app.point_marker = "r.";
            app.point_marker_size = 15;
            app.linewidth = 2;
            
            %constant used to detect if edit field was "touched"
            app.isempty_val = -1;
            
            %maximalni meze zadani hodnot
            %potize s dopocty mezi, v konkretnich pripadech umi az 900
            %app.hodnoty_maxim.t = 900;
            app.hodnoty_maxim.t = 800;
            app.hodnoty_maxim.p = 1000;
            app.hodnoty_maxim.v = 192.4;
            app.hodnoty_maxim.h = 4200;
            app.hodnoty_maxim.u = 10000;
            app.hodnoty_maxim.s = 10;
            app.hodnoty_maxim.x = 1;
            %app.hodnoty_maxim.units = set_xsteam_units(units_class);
            
            app.hodnoty_minim.t = .1;
            app.hodnoty_minim.p = .0062; %minimum vychazi z xsteam(h_pt)
            app.hodnoty_minim.v = .001001;
            app.hodnoty_minim.h = 100;
            app.hodnoty_minim.u = 0;
            app.hodnoty_minim.s = 0;
            app.hodnoty_minim.x = 0;
            %app.hodnoty_minim.units = set_xsteam_units(units_class);
            app.hodnoty_minim_neverchange = app.hodnoty_minim;
            app.hodnoty_maxim_neverchange = app.hodnoty_maxim;
            
            %disable fields which should not be visible unless conditions
            %are met
            app.etaEditField.Editable = 'off';
            app.etaEditField.Visible = 'off';
            app.etaLabel.Visible = 'off';
            lock_d(app);
            
            %sets boolean that checks wheater it is safe "to push the
            %button"
            app.can_calc.p = false;
            app.can_calc.d = false;
            app.can_move = false;
            app.vypocti_pushed.p = false;
            app.vypocti_pushed.d = false;
            app.ulo_pushed = false;
            %autozoom stuff
            app.znama_maxima.t = 400;
            app.znama_maxima.h = 2850;
            app.znama_maxima.s = 10;
            
            app.DropDown.Value = 'izobarický';
            app.etaEditField.Value = .8;
            
            app.mem_deju = dej_class;
            app.mem_deju = app.mem_deju.init__;
            app.mem_bodu = [];
            app.mem_size = 50;
            app.mem_djvelicin = [];

            app.etaLabel.Interpreter = 'latex';

            app.last_diagram = 'T-s';
            
            app.dlg_opts =  struct('WindowStyle','modal','Interpreter','tex');
            app.vypoti_pushed_flag = false;
        end

        % Button pushed function: HledejButton
        function HledejButtonPushed(app, event)
            if app.can_calc.p
                app.can_calc.p = false;
                
                %calc "upper" values
                
                %pripad = dopocet_hodnot_p(app);
                %zjisti pripad zadani
                pripad = zjisti_pripad_zadani(app);
                if pripad == -1
                    warning("unknown zjisti_pripad_zadani in dopocet_hodnot_p")
                    return %chyba, nechci aby to padalo
                end
                
                [v1,v2,v1_unit,v2_unit] = zjisti_vstupni_hodnoty_poc(app,pripad);
                
                %zavolej vypocet
                [t,p,v,s,x,h,u]=dopocetNeznamych_(app,v1,v1_unit,v2,v2_unit,pripad);
%                 %zavolej plot
%                 add_state(app,pripad,t,p,v,s,x,h,u)
                
                % check for nans and show the values
                if isnan(t)
                    t=-1;
                end
                if isnan(p)
                    p=-1;
                end
                if isnan(v)
                    v=-1;
                end
                if isnan(s)
                    s=-1;
                end
                if isnan(x)
                    if t > 373.945 && s > 4.3700
                        x = 1;
                    elseif s < 4.3700
                        x = 0;
                    else
                        x = -1;
                    end
                end
                if isnan(h)
                    h=-1;
                end
                if isnan(u)
                    u=-1;
                end
                app.tEditField.Value=prevod_(app,t,'°C','t',app.dd_p_t.Value);%t+app.prevod_k.t;
                app.pEditField.Value=prevod_(app,p,'bar','p',app.dd_p_p.Value);%p/app.prevod_k.p;
                app.vEditField.Value=prevod_(app,v,'m3/kg','v',app.dd_p_v.Value);%v/app.prevod_k.v;
                app.hEditField.Value=prevod_(app,h,'kJ/kg','h',app.dd_p_h.Value);%h/app.prevod_k.h;
                app.uEditField.Value=prevod_(app,u,'kJ/kg','u',app.dd_p_u.Value);%u/app.prevod_k.u;
                app.sEditField.Value=prevod_(app,s,'kJ/kg/K','s',app.dd_p_s.Value);%s/app.prevod_k.s;
                app.xEditField.Value=x;

                
                %init deje and calc of "lower limits"
                typ = translate_typ_deje(app);
                dej = dej_class;
                dej = dej.init_dej(app,typ,'h');
                dej.apply_obj_meze(app);
                
                % app.mem_bodu = [app.mem_bodu,dej.bod1];
                app.mem_deju = dej;
                
                %plot pomocnych
                app.mem_deju.bod1 = app.mem_deju.bod1.vyrob_pomocne(app,pripad);
                %[z1, z2] = pripad_to_str(app,pripad);
                %app.mem_deju.bod1.zadani1 = z1; 
                %app.mem_deju.bod1.zadani2 = z2; 
                app.mem_deju.bod1.pripad_zadani = pripad; 
                %plot bodu
                app.mem_deju.bod1 = app.mem_deju.bod1.vyrob_bod(app);
                app.mem_deju.apply_obj_meze(app);
                
                %button stuff
                app.vypocti_pushed.p = true;
                app.HledejButton.Enable = 'off';
                
                clear_pocatecniStav_d(app);
                app.ZnovuButton.Enable = 'on';
                
                app.DropDown.Enable = 'on';
                app.etaEditField.Enable = 'on';
                app.VypotiButton.Enable = 'on';
                % this is sooooo bad!
                % app.mem_bodu = [app.mem_bodu,dej.bod1];
                autozoom(app);
                % replot(app);
                % app.mem_bodu = [];
                err_check(app,'ps')
                checkVol(app)
            else
%                 if app.vypocti_pushed.p
%                     warndl("Již je spočítáno.","Vypočti button Warning")
%                 else
%                 %popup info window 'confirm the values before pushing this button'
%                 %warning('to do popup in function HledejButtonPushed(app, event)')
%                 warndl("Není platný stav! Před stiskem 'Vypočti' musejí být všechna políčka šedá.","Vypočti button Warning")
%                 end
            end
            
        end

        % Value changed function: diagramDropDown
        function diagramDropDownValueChanged(app, event)
            if app.UloButton_2.Enable == 'on'
                % dialog uložit?
                answer = questdlg('Uložit poslední děj?', ...
            	'Uložit?','Ano','Ne','Ano');
                %choice = menu(msg,opts);
                if isempty(answer)
                    app.diagramDropDown.Value = app.last_diagram;
                    return
                end
                if strcmp(answer,'Ano')
                     UloButton_2Pushed(app, event);
                end
            end
            
            ZnovupsButtonPushed(app, event)
            
            value = app.diagramDropDown.Value;
            app.last_diagram = value;
            set_plot(app,app.UIAxes,value)
            % replot stuff
            replot(app)
            % first check for memory size
            if isempty(app.mem_bodu)
                % we need to check for first point plot
                if ~isempty(app.mem_deju)
                    if ~isempty(app.mem_deju.bod1.t)
                        app.mem_deju.bod1.vyrob_bod(app)
                    end
                end
                autozoom(app)
                return
            elseif mod(length(app.mem_bodu), 2) ~= 0
                % length of memory has! to be zero or even
                % we know somethin went wrong if it is odd
                warndlg("\fontsize{14}Neočekávaná délka paměti stavů! Resetuji se.","Fatální chyba",app.dlg_opts)
                app.mem_bodu = [];
                VymaButtonPushed(app, event);
            elseif mod(length(app.mem_bodu), 2) == 0
                switch value
                    case 'T-s'
                        
                    case 'h-s'
                        
                    case 'p-v'
                        
                end
                % plot first poit in mem
                
            end
            autozoom(app);
        end

        % Value changed function: dd_p_s
        function dd_p_sValueChanged(app, event)
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'s',app.dd_p_s.Value);
            app.sEditField.Value = double(a);
        end

        % Value changed function: dd_p_p
        function dd_p_pValueChanged(app, event)
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'p',app.dd_p_p.Value);
            app.pEditField.Value = double(a);
        end

        % Value changed function: dd_p_v
        function dd_p_vValueChanged(app, event)
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'v',app.dd_p_v.Value);
            app.vEditField.Value = double(a);
        end

        % Value changed function: dd_p_h
        function dd_p_hValueChanged(app, event)
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'h',app.dd_p_h.Value);
            app.hEditField.Value = double(a);
        end

        % Value changed function: dd_p_u
        function dd_p_uValueChanged(app, event)
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'u',app.dd_p_u.Value);
            app.uEditField.Value = double(a);
        end

        % Value changed function: dd_p_t
        function dd_p_tValueChanged(app, event)
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'t',app.dd_p_t.Value);
            app.tEditField.Value = double(a);
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            value = app.tEditField.Value;
            physical_variable_changed(app,value,'t','h')
            if value > 373.945
                app.xEditField.Editable = 'off';
                app.xEditField.BackgroundColor = [.9 .9 .9];
            end
            %app.tEditField.FontWeight = 'bold';
        end

        % Value changed function: pEditField
        function pEditFieldValueChanged(app, event)
            value = app.pEditField.Value;
            physical_variable_changed(app,value,'p','h')
            %app.pEditField.FontWeight = 'bold';
        end

        % Value changed function: vEditField
        function vEditFieldValueChanged(app, event)
            value = app.vEditField.Value;
            physical_variable_changed(app,value,'v','h')
            %app.vEditField.FontWeight = 'bold';
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            physical_variable_changed(app,value,'h','h')
            %app.hEditField.FontWeight = 'bold';
        end

        % Value changed function: sEditField
        function sEditFieldValueChanged(app, event)
            value = app.sEditField.Value;
            physical_variable_changed(app,value,'s','h')
            %app.sEditField.FontWeight = 'bold';
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            physical_variable_changed(app,value,'x','h')
            %app.xEditField.FontWeight = 'bold';
        end

        % Value changed function: uEditField
        function uEditFieldValueChanged(app, event)
            value = app.uEditField.Value;
            physical_variable_changed(app,value,'u','h')
            %app.uEditField.FontWeight = 'bold';
        end

        % Drop down opening function: dd_p_p
        function dd_p_pDropDownOpening(app, event)
            %q = app.dd_p_p.Value;
            app.hold_unit.val = app.pEditField.Value;
            app.hold_unit.unit = app.dd_p_p.Value;
        end

        % Drop down opening function: dd_p_t
        function dd_p_tDropDownOpening(app, event)
            %app.hold_unit = prevod_in(app,app.tEditField.Value,'t');
            app.hold_unit.val = app.tEditField.Value;
            app.hold_unit.unit = app.dd_p_t.Value;
        end

        % Drop down opening function: dd_p_v
        function dd_p_vDropDownOpening(app, event)
            %app.hold_unit = prevod_in(app,app.vEditField.Value,'v');
            app.hold_unit.val = app.vEditField.Value;
            app.hold_unit.unit = app.dd_p_v.Value;
        end

        % Drop down opening function: dd_p_h
        function dd_p_hDropDownOpening(app, event)
            %app.hold_unit = prevod_in(app,app.hEditField.Value,'h');
            app.hold_unit.val = app.hEditField.Value;
            app.hold_unit.unit = app.dd_p_h.Value;
        end

        % Drop down opening function: dd_p_u
        function dd_p_uDropDownOpening(app, event)
            %app.hold_unit = prevod_in(app,app.uEditField.Value,'u');
            app.hold_unit.val = app.uEditField.Value;
            app.hold_unit.unit = app.dd_p_u.Value;
        end

        % Drop down opening function: dd_p_s
        function dd_p_sDropDownOpening(app, event)
            %app.hold_unit = prevod_in(app,app.sEditField.Value,'s');
            app.hold_unit.val = app.sEditField.Value;
            app.hold_unit.unit = app.dd_p_s.Value;
        end

        % Drop down opening function: dd_d_p
        function dd_d_pDropDownOpening(app, event)
            app.hold_unit.val = app.pEditField_2.Value;
            app.hold_unit.unit = app.dd_d_p.Value;
        end

        % Drop down opening function: dd_d_s
        function dd_d_sDropDownOpening(app, event)
            app.hold_unit.val = app.sEditField_2.Value;
            app.hold_unit.unit = app.dd_d_s.Value;
        end

        % Drop down opening function: dd_d_u
        function dd_d_uDropDownOpening(app, event)
            app.hold_unit.val = app.uEditField_2.Value;
            app.hold_unit.unit = app.dd_d_u.Value;
        end

        % Drop down opening function: dd_d_h
        function dd_d_hDropDownOpening(app, event)
            app.hold_unit.val = app.hEditField_2.Value;
            app.hold_unit.unit = app.dd_d_h.Value;
        end

        % Drop down opening function: dd_d_v
        function dd_d_vDropDownOpening(app, event)
            app.hold_unit.val = app.vEditField_2.Value;
            app.hold_unit.unit = app.dd_d_v.Value;
        end

        % Drop down opening function: dd_d_t
        function dd_d_tDropDownOpening(app, event)
            app.hold_unit.val = app.tEditField_2.Value;
            app.hold_unit.unit = app.dd_d_t.Value;
        end

        % Value changed function: dd_d_t
        function dd_d_tValueChanged(app, event)
            %value = app.dd_d_t.Value;
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'t',app.dd_d_t.Value);
            app.tEditField_2.Value = double(a);
        end

        % Value changed function: dd_d_v
        function dd_d_vValueChanged(app, event)
            %value = app.dd_d_v.Value;
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'v',app.dd_d_v.Value);
            app.vEditField_2.Value = double(a);
        end

        % Value changed function: dd_d_h
        function dd_d_hValueChanged(app, event)
            %value = app.dd_d_h.Value;
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'h',app.dd_d_h.Value);
            app.hEditField_2.Value = double(a);
        end

        % Value changed function: dd_d_u
        function dd_d_uValueChanged(app, event)
            %value = app.dd_d_u.Value;
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'u',app.dd_d_u.Value);
            app.uEditField_2.Value = double(a);
        end

        % Value changed function: dd_d_s
        function dd_d_sValueChanged(app, event)
            %value = app.dd_d_s.Value;
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'s',app.dd_d_s.Value);
            app.sEditField_2.Value = double(a);
        end

        % Value changed function: dd_d_p
        function dd_d_pValueChanged(app, event)
            %value = app.dd_d_p.Value;
            a = prevod_(app,app.hold_unit.val,app.hold_unit.unit,'p',app.dd_d_p.Value);
            app.pEditField_2.Value = double(a);
        end

        % Value changed function: tEditField_2
        function tEditField_2ValueChanged(app, event)
            value = app.tEditField_2.Value;
            physical_variable_changed(app,value,'t','d')
            %this is not nice, elegant nor is it robust but it should work
            if app.tEditField_2.Value ~= app.isempty_val
                lock_d(app)
                app.can_calc.d = true;
            end
        end

        % Value changed function: pEditField_2
        function pEditField_2ValueChanged(app, event)
            value = app.pEditField_2.Value;
            physical_variable_changed(app,value,'p','d')
            %this is not nice, elegant nor is it robust but it should work
            if app.pEditField_2.Value ~= app.isempty_val
                lock_d(app)
                app.can_calc.d = true;
            end
        end

        % Value changed function: vEditField_2
        function vEditField_2ValueChanged(app, event)
            value = app.vEditField_2.Value;
            physical_variable_changed(app,value,'v','d')
            %this is not nice, elegant nor is it robust but it should work
            if app.vEditField_2.Value ~= app.isempty_val
                lock_d(app)
                app.can_calc.d = true;
            end
        end

        % Value changed function: hEditField_2
        function hEditField_2ValueChanged(app, event)
            value = app.hEditField_2.Value;
            physical_variable_changed(app,value,'h','d')
            %this is not nice, elegant nor is it robust but it should work
            if app.hEditField_2.Value ~= app.isempty_val
                lock_d(app)
                app.can_calc.d = true;
            end
        end

        % Value changed function: sEditField_2
        function sEditField_2ValueChanged(app, event)
            value = app.sEditField_2.Value;
            physical_variable_changed(app,value,'s','d')
            %this is not nice, elegant nor is it robust but it should work
            if app.sEditField_2.Value ~= app.isempty_val
                lock_d(app)
                app.can_calc.d = true;
            end
        end

        % Value changed function: xEditField_2
        function xEditField_2ValueChanged(app, event)
            value = app.xEditField_2.Value;
            physical_variable_changed(app,value,'x','d')
            %this is not nice, elegant nor is it robust but it should work
            if app.xEditField_2.Value ~= app.isempty_val
                lock_d(app)
                app.can_calc.d = true;
            end
            
        end

        % Value changed function: uEditField_2
        function uEditField_2ValueChanged(app, event)
            value = app.uEditField_2.Value;
            physical_variable_changed(app,value,'u','d')
            %this is not nice, elegant nor is it robust but it should work
            if app.uEditField_2.Value ~= app.isempty_val
                lock_d(app)
                app.can_calc.d = true;
            end
        end

        % Button pushed function: VypotiButton
        function VypotiButtonPushed(app, event)
            if app.can_calc.d == true 
                app.vypoti_pushed_flag = true;
                app.ulo_pushed = false;
                app.can_calc.d = false;
                try
                % smazni pomocne prvniho stavu
                app.mem_deju.bod1.delete_pomocne;
                app.vypocti_pushed.d = true;
                dej = app.DropDown.Value;
                dej = dej_to_num(dej);

                %lizni si stav 1
                % already is in xsteam units
                [t,p,v,h,u,s,x] = get_poc_stav_vals(app);

                %lizni si stav 2
                % already in xsteam units
                [t2,p2,v2,h2,u2,s2,x2] = get_konc_stav_vals(app);

                %zjisti pripad dopoctu koncoveho bodu
                pripad = zjisti_pripad_deje(app,dej,t2,p2,v2,h2,u2,s2,x2);
                [outH_hs,outS_hs,outT_ts,outS_ts,t2,p2,v2,h2,u2,s2,x2] = swtich_dej(app,pripad,dej,t,p,v,h,u,s,x,t2,p2,v2,h2,u2,s2,x2);

                %show values in "d" edit fields 
                
                ukazKoncStav(app,t2,p2,v2,h2,s2,x2,u2);
                            
                % detekce zadani pro vyrazeni plotu pomocne h adiabateckeho
                % deje
                if app.DropDown.Value == "adiabatický"
                    % translate pripad to proper helper line (p)
                    switch pripad
                        case 2 %hs
                            pripad_ = 1; %hp
                        case 5 %st
                            pripad_ = 3; %tp
                        case 6 %sp
                            pripad_ = 6; %nonsense case
                        case 10 %sx
                            pripad_ = 8; %px
                        case 14 %sv
                            pripad_ = 12; %pv
                        case 19 %su
                            warning('NYI -> VypotiButtonPushed callback for ks defined via u')
                            pripad_ = 19; %nyi
                    end
                    app.mem_deju = app.mem_deju.set_bod2(app,pripad_,"skip_h");
                else
                    app.mem_deju = app.mem_deju.set_bod2(app,pripad);
                end
                app.mem_deju.bod2.pripad_zadani = pripad;
                %plot cary deje
                [l1,txt1] = plotDeje(app,outH_hs,outS_hs,outT_ts,outS_ts);
                app.mem_deju.dej_pointer1 = l1;
                app.mem_deju.bod2.dj_label = txt1;

                %calc and show "extras" q, wo, wt
                [q,wo,wp] = djveliciny_hs(app,outH_hs,outS_hs,outT_ts,outS_ts);
                app.qEditField.Value = q; 
                app.woEditField.Value = wo;
                app.wtEditField.Value = wp;
                show_dj_panel(app);
                
                %             %check wheather it is safe to enable "Posuň" button action
                %             if t2 ~= -1 && p2 ~= -1 && h2 ~= -1 && s2 ~= -1
                %                 app.can_move = true;
                %             end
                app.VypotiButton.Enable = 'off';
                app.UloButton_2.Enable = 'on';
                app.VypotiButton.Enable = 'on';
                app.ZnovuButton.Enable = 'on';
                app.DropDown.Enable = 'off';
                if strcmp(app.DropDown.Value, 'adiabatický')
                    app.etaEditField.Editable = 'off';
                    app.etaEditField.BackgroundColor = [.9 .9 .9];
                end
                autozoom(app)
                err_check(app,'ks')
                app.vypoti_pushed_flag = false;
                catch ME
                    if strcmp(ME.message,'adia vypocet')
                        msg = ['Chyba výpočtu adiabatického děje.',newline,'Pro zadané parametry je maximální možné '];
                        switch pripad
                            case 2
                                value = app.errValue;
                                add_this = ['h = ',num2str(value),' kJ/kg'];
                            case 5
                                value = app.errValue;
                                add_this = ['t = ',num2str(value),' °C'];
                            case 6
                                %should not happen
                                %add_this = '';
                            case 10
                                %should not happen
                                %add_this = '';
                            case 14
                                %should not happen
                                %add_this = '';
                        end
                        msg = [msg, add_this];
                        errordlg(msg)
                    else
                        errordlg('Cyhba výpočtu')
                    end
                    ZnovuButtonPushed(app,event);
                end
            else
                if app.vypocti_pushed.d == false
                    % this option is now obsolete
                    % warndlg("\fontsize{14}Není platný stav! Před stiskem 'Vypočti' musejí být všechna políčka šedá.","Vypočti děj button Warning",app.dlg_opts)
                else
                    warndlg("\fontsize{14}Již je spočítáno","Vypočti děj button Warning",app.dlg_opts)
                end
            end
            
            %lock / unlock appropriate ef
            %app.VymaButton.Enable = 'off';
            
        end

        % Button pushed function: VymaButton
        function VymaButtonPushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'No';
            answer = questdlg('Opravdu?', ...
            	'Reset', ...
            	'Ano','Ne',...
                opts);
            % Handle response
            switch answer
                case 'Ano'
                    % continue
                case 'Ne'
                    % do nothing
                    return
            end
            clear_pocatecniStav_p(app)
            % tento button pameť nemaže
            % app.mem_bodu = [];
            
            app.can_calc.p = false;
            app.hold_stavu.poc = 0;
            app.hold_stavu.variables = [];
            app.vypocti_pushed.p = false;
            
            unlock_p(app);
            
            app.hodnoty_maxim = app.hodnoty_maxim_neverchange;
            app.hodnoty_minim = app.hodnoty_minim_neverchange;
            
            %vymaž graf
            cla(app.UIAxes)
            value = app.diagramDropDown.Value;
            set_plot(app,app.UIAxes,value)
            autozoom(app)

            %reset koncoveho stavu
            clear_pocatecniStav_d(app)
            lock_d(app);
            
            %vymaž paměť aktivního děje
            % app.mem_deju = dej_class.empty;
            app.mem_bodu = [];
            app.mem_djvelicin = [];
            
            %button stuff
            app.lock_d
            app.UloButton_2.Enable = 'off';
            app.VypotiButton.Enable = 'off';
            app.ZnovuButton.Enable = 'off';
            app.DropDown.Enable = 'off';
            app.HledejButton.Enable = 'on';
            app.etaEditField.BackgroundColor = [1 1 1];
            % startupFcn(app)
            app.hold_stavu.poc = 0;
            app.hold_stavu.variables = [];
            %inicializace plotu 1 a 2
            %cla(app.UIAxes)
            hold(app.UIAxes,'on');
            fig = openfig('figs\ts_mez.fig','invisible');
            ax = findobj(fig,'Type','Axes');
            copyobj(ax.Children,app.UIAxes);
            hold(app.UIAxes,'on');
            clear fig ax
            % change "home" callback in graph
            % https://www.mathworks.com/matlabcentral/answers/879193-how-can-i-customize-the-callback-of-the-restore-view-built-in-option-on-the-toolstrip-of-a-uiaxes
            % Get the toolbar axes
            axToolstrip = axtoolbar(app.UIAxes, 'default');
            % Get the restore view button and reset the callback function
            isRestoreButton = strcmpi({axToolstrip.Children.Icon}, 'restoreview');
            restoreButtonHandle = axToolstrip.Children(isRestoreButton);
            restoreButtonHandle.ButtonPushedFcn = @(~,ev) restoreViewCallback(app, ev);
            
                        
            %parametry plotu
            app.point_marker = "r.";
            app.point_marker_size = 15;
            app.linewidth = 2;
            
            %constant used to detect if edit field was "touched"
            app.isempty_val = -1; 
            %disable fields which should not be visible unless conditions
            %are met
            app.etaEditField.Editable = 'off';
            app.etaEditField.Visible = 'off';
            app.etaLabel.Visible = 'off';
            lock_d(app);
            app.NatiButton.Enable = 'off';
            
            %sets boolean that checks wheater it is safe "to push the
            %button"
            app.can_calc.p = false;
            app.can_calc.d = false;
            app.can_move = false;
            app.vypocti_pushed.p = false;
            app.vypocti_pushed.d = false;
            app.ulo_pushed = false;
            %autozoom stuff
            app.znama_maxima.t = 400;
            app.znama_maxima.h = 2850;
            app.znama_maxima.s = 10;
            
            app.DropDown.Value = 'izobarický';
            app.etaEditField.Value = .8;
            
            app.mem_deju = dej_class;
            app.mem_deju = app.mem_deju.init__;
        end

        % Button pushed function: UloButton_2
        function UloButton_2Pushed(app, event)
            app.UloButton_2.Enable = "off";
            app.NatiButton.Enable = "on";
            
            app.ulo_pushed = true;
            % poc bod
%             t = app.tEditField.Value;
%             p = app.pEditField.Value;
%             v = app.vEditField.Value;
%             h = app.hEditField.Value;
%             u = app.uEditField.Value;
%             s = app.sEditField.Value;
%             x = app.xEditField.Value;
            
            bod1 = bod_class;
            bod1 = bod1.read_stavu(app,'h');
            xstmunits = units_class;
            xstmunits = xstmunits.set_xsteam_units;
            bod1 = bod1.prevod_vsech(app,bod1.units,xstmunits);
            % get zadani 1 a 2
            kontrola_poctu_zadani = 1;
                if app.tEditField.FontWeight == "bold" && kontrola_poctu_zadani < 3
                    if kontrola_poctu_zadani == 1
                        bod1.zadani1 = "t";
                    elseif kontrola_poctu_zadani == 2
                        bod1.zadani2 = "t";
                    end
                    kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
                end
                if app.pEditField.FontWeight == "bold" && kontrola_poctu_zadani < 3
                    if kontrola_poctu_zadani == 1
                        bod1.zadani1 = "p";
                    elseif kontrola_poctu_zadani == 2
                        bod1.zadani2 = "p";
                    end
                    kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
                end
                if app.vEditField.FontWeight == "bold" && kontrola_poctu_zadani < 3
                    if kontrola_poctu_zadani == 1
                        bod1.zadani1 = "v";
                    elseif kontrola_poctu_zadani == 2
                        bod1.zadani2 = "v";
                    end
                    kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
                end
                if app.hEditField.FontWeight == "bold" && kontrola_poctu_zadani < 3
                    if kontrola_poctu_zadani == 1
                        bod1.zadani1 = "h";
                    elseif kontrola_poctu_zadani == 2
                        bod1.zadani2 = "h";
                    end
                    kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
                end
                if app.sEditField.FontWeight == "bold" && kontrola_poctu_zadani < 3
                    if kontrola_poctu_zadani == 1
                        bod1.zadani1 = "s";
                    elseif kontrola_poctu_zadani == 2
                        bod1.zadani2 = "s";
                    end
                    kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
                end
                if app.xEditField.FontWeight == "bold" && kontrola_poctu_zadani < 3
                    if kontrola_poctu_zadani == 1
                        bod1.zadani1 = "x";
                    elseif kontrola_poctu_zadani == 2
                        bod1.zadani2 = "x";
                    end
                    kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
                end
                if app.uEditField.FontWeight == "bold" && kontrola_poctu_zadani < 3
                    if kontrola_poctu_zadani == 1
                        bod1.zadani1 = "u";
                    elseif kontrola_poctu_zadani == 2
                        bod1.zadani2 = "u";
                    end
                    % kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
                end
            
            % koncový bod
            [t,p,v,h,u,s,x] = get_konc_stav_vals(app);
%             t = app.tEditField_2.Value;
%             p = app.pEditField_2.Value;
%             v = app.vEditField_2.Value;
%             h = app.hEditField_2.Value;
%             u = app.uEditField_2.Value;
%             s = app.sEditField_2.Value;
%             x = app.xEditField_2.Value;
            
            bod = bod_class;
            bod = bod.setVals(t,p,v,h,u,s,x);
            bod.units = bod.units.read_units_d(app);
            bod.zadani1 = app.DropDown.Value;
            if app.DropDown.Value == "adiabatický"
                bod.zadani_ucinnost = app.etaEditField.Value;
            end
            kontrola_poctu_zadani = 2;
            if app.tEditField_2.FontWeight == "bold" && kontrola_poctu_zadani < 3
                if kontrola_poctu_zadani == 2
                    bod.zadani2 = "t";
                end
                kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
            end
            if app.pEditField_2.FontWeight == "bold" && kontrola_poctu_zadani < 3
                if kontrola_poctu_zadani == 2
                    bod.zadani2 = "p";
                end
                kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
            end
            if app.vEditField_2.FontWeight == "bold" && kontrola_poctu_zadani < 3
                if kontrola_poctu_zadani == 2
                    bod.zadani2 = "v";
                end
                kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
            end
            if app.hEditField_2.FontWeight == "bold" && kontrola_poctu_zadani < 3
                if kontrola_poctu_zadani == 2
                    bod.zadani2 = "h";
                end
                kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
            end
            if app.sEditField_2.FontWeight == "bold" && kontrola_poctu_zadani < 3
                if kontrola_poctu_zadani == 2
                    bod.zadani2 = "s";
                end
                kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
            end
            if app.xEditField_2.FontWeight == "bold" && kontrola_poctu_zadani < 3
                if kontrola_poctu_zadani == 2
                    bod.zadani2 = "x";
                end
                kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
            end
            if app.uEditField_2.FontWeight == "bold" && kontrola_poctu_zadani < 3
                if kontrola_poctu_zadani == 2
                    bod.zadani2 = "u";
                end
                % kontrola_poctu_zadani = kontrola_poctu_zadani + 1;
            end
            
            
            if length(app.mem_bodu) >= app.mem_size
                app.mem_bodu = app.mem_bodu(2:app.mem_size);
            end
            bod1.pripad_zadani = app.mem_deju.bod1.pripad_zadani;
            bod.pripad_zadani = app.mem_deju.bod2.pripad_zadani;
            app.mem_bodu = [app.mem_bodu,bod1,bod];
            % app.mem_bodu = [app.mem_bodu,app.mem_deju.bod1,app.mem_deju.bod2];
            %save dj veiciny
            new_dj = djveliciny;
            app.mem_djvelicin = [app.mem_djvelicin, new_dj.save_djveliciny(app)];
        end

        % Button pushed function: ZnovuButton
        function ZnovuButtonPushed(app, event)
%            if app.vypocti_pushed.p
                clear_pocatecniStav_d(app)
                app.UloButton_2.Enable = 'off';
                app.can_calc.d = false;
                app.vypocti_pushed.d = false;
                
                app.VypotiButton.Enable = 'on';
                
                if app.ulo_pushed == false
                    if ~isempty(app.mem_deju.bod2)
                        delete_bodu(app.mem_deju.bod2);
                    end
                    if ~isempty(app.mem_deju.bod2_)
                        delete_bodu(app.mem_deju.bod2_);
                    end
                    if ~isempty(app.mem_deju.dej_pointer1)
                        delete(app.mem_deju.dej_pointer1);
                        delete(app.mem_deju.dej_pointer2);
                        delete(app.mem_deju.bod2.dj_label)
                    end
                else % data is saved, switch helper lines    
                    % delete lastest
                    app.mem_deju.bod2.delete_pomocne();
                    app.mem_deju.bod2_.delete_pomocne();
                    app.mem_deju.bod2_.delete_bodu();
                end
                % reset part of memory
                app.mem_deju.bod2 = bod_class;
                app.mem_deju.bod2_ = bod_class;
                
                % show "step back"
                pripad = app.mem_deju.bod1.pripad_zadani;
                app.mem_deju.bod1 = app.mem_deju.bod1.vyrob_pomocne(app,pripad);
                hide_dj_panel(app);
                % unlock dej dropdown
                app.DropDown.Enable = 'on';
                if strcmp(app.DropDown.Value, 'adiabatický')
                    app.etaEditField.Editable = 'on';
                    app.etaEditField.BackgroundColor = [1 1 1];
                end
%            else
                %nothing?
            %end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            if strcmp(value,'izochorický')
                checkVol(app)
%                 v = prevod_(app,app.vEditField.Value,app.dd_p_v.Value,'v','m3/kg');
%                 if v < .002
%                     txt = ['Izochorický děj pro v=',num2str(v),' nespočítám.',newline(),'Minimální hodnota v=0.002'];
%                     warndlg(txt)
%                     app.VypotiButton.Enable = 'off';
%                 end
            elseif strcmp(value,'adiabatický')
                app.VypotiButton.Enable = 'on';
                app.etaEditField.Editable = 'on';
                app.etaEditField.Visible = 'on';
                app.etaLabel.Visible = 'on';
                %app.etaEditField.BackgroundColor = [1 1 1];
                %app.etaEditField.Label = 'on';
            else
                app.VypotiButton.Enable = 'on';
                app.etaEditField.Editable = 'off';
                app.etaEditField.Visible = 'off';
                app.etaLabel.Visible = 'off';
                %app.etaEditField.BackgroundColor = [.9 .9 .9];
%                 app.etaEditField.Enable = 'off';
%                 app.etaEditField.Visible = 'off';
            end
            %checking upper editfields to see if vypočti was pushed
            tef = app.tEditField.Editable;
            pef = app.pEditField.Editable;
            pefd = app.pEditField_2.Editable;
            tefd = app.tEditField_2.Editable;
            if pef=="off" && tef == "off" && (tefd == "on" || pefd == "on")
%                 clear_pocatecniStav_d(app)
                %typ = app.DropDown.Value;
                unlock_d(app)
                lock_d(app,value)
                
                switch value
                    case "izobarický"
                        app.pEditField_2.Value = app.isempty_val;
                    case "izotermický"
                        app.tEditField_2.Value = app.isempty_val;
                    case "izochorický"
                        app.vEditField_2.Value = app.isempty_val;
                    case "izoentropický"
                        app.sEditField_2.Value = app.isempty_val;
                    case "adiabatický"
                        app.sEditField_2.Value = app.isempty_val;
                    case "izoentalpický"
                        app.hEditField_2.Value = app.isempty_val;
                end
            end
            switch value
                case "izobarický"
                    typ = 'p';
                case "izotermický"
                    typ = 't';
                case "izochorický"
                    typ = 'v';
                case "izoentropický"
                    typ = 's';
                case "adiabatický"
                    typ = 's';
                case "izoentalpický"
                    typ = 'h';
            end
            app.mem_deju.typ_deje = typ;
            app.mem_deju = app.mem_deju.calc_mezi(app);
            app.mem_deju.apply_obj_meze(app);
        end

        % Button pushed function: NatiButton
        function NatiButtonPushed(app, event)
            %[t,p,v,h,u,s,x] = deal(-1);
            
            if ~isempty(app.mem_bodu)
                %app.mem_select_app = memory_menu(app,app.mem_bodu);
                
%                 [t,p,v,h,u,s,x] = app.mem_deju.bod2.getVals;
%                 
%                 app.tEditField.Value=t;
%                 app.pEditField.Value=p;
%                 app.vEditField.Value=v;
%                 app.hEditField.Value=h;
%                 app.uEditField.Value=u;
%                 app.sEditField.Value=s;
%                 app.xEditField.Value=x;
%                 
                %lock_p(app)
                %lock ps editfields
                app.tEditField.Editable = 'off';
                app.pEditField.Editable = 'off';
                app.vEditField.Editable = 'off';
                app.hEditField.Editable = 'off';
                app.sEditField.Editable = 'off';
                app.xEditField.Editable = 'off';
                app.tEditField.BackgroundColor = [.9 .9 .9];
                app.pEditField.BackgroundColor = [.9 .9 .9];
                app.vEditField.BackgroundColor = [.9 .9 .9];
                app.hEditField.BackgroundColor = [.9 .9 .9];
                app.sEditField.BackgroundColor = [.9 .9 .9];
                app.xEditField.BackgroundColor = [.9 .9 .9];
                
                %lock hledej
                app.HledejButton.Enable = 'off';
                
                %unlock dj
                app.DropDown.Enable = 'on';
                app.etaEditField.Enable = 'on';
                %unlock znovu
                app.ZnovuButton.Enable = 'on';
                
                %unlock ks editfields
                %clear_pocatecniStav_d(app)
                
%                 %load to mem current PS to active mem
%                 app.mem_deju.bod1 = app.mem_deju.bod2;
%                 app.mem_deju.bod2 = bod_class;
%                 app.mem_deju.bod2_ = bod_class;
                % app.VypotiButton.Enable = 'off';
                app.NatiButton.Enable = "off";
                app.mem_select_app = dataselection(app);
            end
            
        end

        % Close request function: VodnpraUIFigure
        function VodnpraUIFigureCloseRequest(app, event)
            
            delete(app.mem_select_app)
            % clear app.hold_stavu app.konst
            % delete(findall(0,'Tag','testtag'))
            delete(findall(0,'Tag','paratag'))
            delete(app);

        end

        % Button pushed function: ZnovupsButton
        function ZnovupsButtonPushed(app, event)
           app.clear_pocatecniStav_p;
           app.unlock_p;
           app.Panel.Enable = 'off';
           app.Panel.Visible = 'off';
           app.clear_pocatecniStav_d;
           app.lock_d;
           app.ZnovuButton.Enable = 'off';
           app.VypotiButton.Enable = 'off';
           app.UloButton_2.Enable = 'off';
           app.HledejButton.Enable = 'on';
           if app.ulo_pushed == false
               app.mem_deju.bod1.delete_bodu();
               app.mem_deju.bod2.delete_bodu();
               delete(app.mem_deju.dej_pointer1);
               delete(app.mem_deju.dej_pointer2);
               delete(app.mem_deju.bod2.dj_label);
           end
           app.mem_deju.bod1.delete_pomocne();
           app.mem_deju.bod2.delete_pomocne();
           app.mem_deju.bod2_.delete_bodu();
           
           app.mem_deju.bod1 = bod_class;
           app.mem_deju.bod2 = bod_class;
           app.mem_deju.bod2_ = bod_class;
           app.DropDown.Enable = 'off';
           app.etaEditField.Enable = 'off';
           %app.etaEditField.Visible = 'off';
           %app.etaLabel.Visible = 'off';
           app.hodnoty_maxim = app.hodnoty_maxim_neverchange;
           app.hodnoty_minim = app.hodnoty_minim_neverchange;
           app.ulo_pushed = false;
           app.vypocti_pushed.p = false;
           app.vypoti_pushed_flag = false;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           opts.Interpreter = 'tex';
           opts.Default = 'No';
           answer = questdlg('Opravdu?', ...
            	'Exit', ...
            	'Ano','Ne',...
                opts);
            % Handle response
            switch answer
                case 'Ano'
                    % exit callback
                    VodnpraUIFigureCloseRequest(app,event);
                case 'Ne'
                    % do nothing
            end
        end

        % Button pushed function: UloDiagramButton
        function UloDiagramButtonPushed(app, event)
            if app.UloButton_2.Enable == 'on'
                % dialog uložit?
                answer = questdlg('Uložit poslední děj?', ...
            	'Uložit?','Ano','Ne','Ano');
                %choice = menu(msg,opts);
                if isempty(answer)
                    return
                end
                if strcmp(answer,'Ano')
                     UloButton_2Pushed(app, event);
                end
            end
            value = app.diagramDropDown.Value;
            diagram_to_pdf(app,value,app.mem_bodu);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VodnpraUIFigure = uifigure('Visible', 'off');
            app.VodnpraUIFigure.Name = 'Program pro výpočty termodynamických dějů ve vodní páře';
            app.VodnpraUIFigure.CloseRequestFcn = createCallbackFcn(app, @VodnpraUIFigureCloseRequest, true);
            app.DjPanel = uipanel(app.VodnpraUIFigure);
            app.DjPanel.Title = '2. Děj';
            app.GridLayout2 = uigridlayout(app.DjPanel);
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'izobarický p=konst', 'izotermický t=konst', 'izochorický v=konst', 'izoentropický s=konst', 'adiabatický q=0', 'izoentalpický h=konst'};
            app.DropDown.ItemsData = {'izobarický', 'izotermický', 'izochorický', 'izoentropický', 'adiabatický', 'izoentalpický'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'izobarický';
            app.DropDownLabel = uilabel(app.GridLayout2);
            app.DropDownLabel.Text = '';
            app.etaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.etaEditField.Limits = [0 1];
            app.etaEditField.Value = 0.8;
            app.etaLabel = uilabel(app.GridLayout2);
            app.etaLabel.Text = '$\eta =';
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.Enable = 'off';
            app.Panel.Visible = 'off';
            app.GridLayout_3 = uigridlayout(app.Panel);
            app.woEditFieldLabel = uilabel(app.GridLayout_3);
            app.woEditFieldLabel.Text = 'wo=';
            app.woEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.woEditField.Editable = 'off';
            app.woEditField.Value = -1;
            app.wtEditFieldLabel = uilabel(app.GridLayout_3);
            app.wtEditFieldLabel.Text = 'wt=';
            app.wtEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.wtEditField.Editable = 'off';
            app.wtEditField.Value = -1;
            app.sdlenteploLabel = uilabel(app.GridLayout_3);
            app.sdlenteploLabel.Text = 'sdělené teplo';
            app.objemovprceLabel = uilabel(app.GridLayout_3);
            app.objemovprceLabel.Text = 'objemová práce';
            app.tlakovprceLabel = uilabel(app.GridLayout_3);
            app.tlakovprceLabel.Text = 'tlaková práce';
            app.dd_q = uidropdown(app.GridLayout_3);
            app.dd_q.Items = {'J/kg', 'kJ/kg', 'MJ/kg'};
            app.dd_q.Value = 'kJ/kg';
            app.dd_wo = uidropdown(app.GridLayout_3);
            app.dd_wo.Items = {'J/kg', 'kJ/kg', 'MJ/kg'};
            app.dd_wo.Value = 'kJ/kg';
            app.dd_wt = uidropdown(app.GridLayout_3);
            app.dd_wt.Items = {'J/kg', 'kJ/kg', 'MJ/kg'};
            app.dd_wt.Value = 'kJ/kg';
            app.qEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.qEditField.Editable = 'off';
            app.qEditField.Value = -1;
            app.qEditFieldLabel = uilabel(app.GridLayout_3);
            app.qEditFieldLabel.Text = 'q=';
            app.Panel_3 = uipanel(app.VodnpraUIFigure);
            app.diagramDropDownLabel = uilabel(app.Panel_3);
            app.diagramDropDownLabel.Text = 'diagram';
            app.diagramDropDown = uidropdown(app.Panel_3);
            app.diagramDropDown.Items = {'T-s', 'h-s', 'p-v'};
            app.diagramDropDown.ValueChangedFcn = createCallbackFcn(app, @diagramDropDownValueChanged, true);
            app.diagramDropDown.Value = 'T-s';
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, 's (kJ/kg/K)')
            ylabel(app.UIAxes, 't (°C)')
            app.Panel_5 = uipanel(app.VodnpraUIFigure);
            app.PotenstavPanel = uipanel(app.Panel_5);
            app.PotenstavPanel.Title = '1. Počáteční stav';
            app.GridLayout = uigridlayout(app.PotenstavPanel);
            app.tEditField_3Label = uilabel(app.GridLayout);
            app.tEditField_3Label.Text = 't=';
            app.tEditField = uieditfield(app.GridLayout, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            app.tEditField.Value = -1;
            app.pEditField_3Label = uilabel(app.GridLayout);
            app.pEditField_3Label.Text = 'p=';
            app.pEditField = uieditfield(app.GridLayout, 'numeric');
            app.pEditField.ValueChangedFcn = createCallbackFcn(app, @pEditFieldValueChanged, true);
            app.pEditField.Value = -1;
            app.vEditField_3Label = uilabel(app.GridLayout);
            app.vEditField_3Label.Text = 'v=';
            app.vEditField = uieditfield(app.GridLayout, 'numeric');
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @vEditFieldValueChanged, true);
            app.vEditField.Value = -1;
            app.hEditField_3Label = uilabel(app.GridLayout);
            app.hEditField_3Label.Text = 'h=';
            app.hEditField = uieditfield(app.GridLayout, 'numeric');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Value = -1;
            app.uEditField_3Label = uilabel(app.GridLayout);
            app.uEditField_3Label.Text = 'u=';
            app.uEditField = uieditfield(app.GridLayout, 'numeric');
            app.uEditField.ValueChangedFcn = createCallbackFcn(app, @uEditFieldValueChanged, true);
            app.uEditField.Editable = 'off';
            app.uEditField.Value = -1;
            app.sEditField_3Label = uilabel(app.GridLayout);
            app.sEditField_3Label.Text = 's=';
            app.sEditField = uieditfield(app.GridLayout, 'numeric');
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @sEditFieldValueChanged, true);
            app.sEditField.Value = -1;
            app.xEditField_3Label = uilabel(app.GridLayout);
            app.xEditField_3Label.Text = 'x=';
            app.xEditField = uieditfield(app.GridLayout, 'numeric');
            app.xEditField.Limits = [-1 1];
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = -1;
            app.dd_p_p = uidropdown(app.GridLayout);
            app.dd_p_p.Items = {'Pa', 'kPa', 'MPa', 'bar'};
            app.dd_p_p.DropDownOpeningFcn = createCallbackFcn(app, @dd_p_pDropDownOpening, true);
            app.dd_p_p.ValueChangedFcn = createCallbackFcn(app, @dd_p_pValueChanged, true);
            app.dd_p_p.Value = 'bar';
            app.teplotaLabel = uilabel(app.GridLayout);
            app.teplotaLabel.Text = 'teplota';
            app.tlakLabel = uilabel(app.GridLayout);
            app.tlakLabel.Text = 'tlak';
            app.objemLabel = uilabel(app.GridLayout);
            app.objemLabel.Text = 'objem';
            app.entalpieLabel = uilabel(app.GridLayout);
            app.entalpieLabel.Text = 'entalpie';
            app.energieLabel = uilabel(app.GridLayout);
            app.energieLabel.Text = 'energie';
            app.entropieLabel = uilabel(app.GridLayout);
            app.entropieLabel.Text = 'entropie';
            app.suchostLabel = uilabel(app.GridLayout);
            app.suchostLabel.Text = 'suchost';
            app.dd_p_t = uidropdown(app.GridLayout);
            app.dd_p_t.Items = {'°C', 'K'};
            app.dd_p_t.DropDownOpeningFcn = createCallbackFcn(app, @dd_p_tDropDownOpening, true);
            app.dd_p_t.ValueChangedFcn = createCallbackFcn(app, @dd_p_tValueChanged, true);
            app.dd_p_t.Value = '°C';
            app.dd_p_v = uidropdown(app.GridLayout);
            app.dd_p_v.Items = {'dm3/kg', 'm3/kg'};
            app.dd_p_v.DropDownOpeningFcn = createCallbackFcn(app, @dd_p_vDropDownOpening, true);
            app.dd_p_v.ValueChangedFcn = createCallbackFcn(app, @dd_p_vValueChanged, true);
            app.dd_p_v.Value = 'm3/kg';
            app.dd_p_h = uidropdown(app.GridLayout);
            app.dd_p_h.Items = {'J/kg', 'kJ/kg'};
            app.dd_p_h.DropDownOpeningFcn = createCallbackFcn(app, @dd_p_hDropDownOpening, true);
            app.dd_p_h.ValueChangedFcn = createCallbackFcn(app, @dd_p_hValueChanged, true);
            app.dd_p_h.Value = 'kJ/kg';
            app.dd_p_u = uidropdown(app.GridLayout);
            app.dd_p_u.Items = {'J/kg', 'kJ/kg'};
            app.dd_p_u.DropDownOpeningFcn = createCallbackFcn(app, @dd_p_uDropDownOpening, true);
            app.dd_p_u.ValueChangedFcn = createCallbackFcn(app, @dd_p_uValueChanged, true);
            app.dd_p_u.Value = 'kJ/kg';
            app.dd_p_s = uidropdown(app.GridLayout);
            app.dd_p_s.Items = {'J/kg/K', 'kJ/kg/K'};
            app.dd_p_s.DropDownOpeningFcn = createCallbackFcn(app, @dd_p_sDropDownOpening, true);
            app.dd_p_s.ValueChangedFcn = createCallbackFcn(app, @dd_p_sValueChanged, true);
            app.dd_p_s.Value = 'kJ/kg/K';
            app.dd_p_x = uidropdown(app.GridLayout);
            app.dd_p_x.Items = {'-'};
            app.dd_p_x.Value = '-';
            app.HledejButton = uibutton(app.Panel_5, 'push');
            app.HledejButton.ButtonPushedFcn = createCallbackFcn(app, @HledejButtonPushed, true);
            app.HledejButton.Text = {'Hledej'; ''};
            app.NatiButton = uibutton(app.Panel_5, 'push');
            app.NatiButton.ButtonPushedFcn = createCallbackFcn(app, @NatiButtonPushed, true);
            app.NatiButton.Enable = 'off';
            app.NatiButton.Text = 'Paměť';
            app.ZnovupsButton = uibutton(app.Panel_5, 'push');
            app.ZnovupsButton.ButtonPushedFcn = createCallbackFcn(app, @ZnovupsButtonPushed, true);
            app.ZnovupsButton.Text = {'Znovu'; ''};
            app.Panel_6 = uipanel(app.VodnpraUIFigure);
            app.KoncovstavPanel = uipanel(app.Panel_6);
            app.KoncovstavPanel.Title = '3. Koncový stav';
            app.GridLayout_2 = uigridlayout(app.KoncovstavPanel);
            app.tEditField_2Label = uilabel(app.GridLayout_2);
            app.tEditField_2Label.Text = 't=';
            app.tEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.tEditField_2.ValueChangedFcn = createCallbackFcn(app, @tEditField_2ValueChanged, true);
            app.tEditField_2.Value = -1;
            app.pEditField_2Label = uilabel(app.GridLayout_2);
            app.pEditField_2Label.Text = 'p=';
            app.pEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.pEditField_2.ValueChangedFcn = createCallbackFcn(app, @pEditField_2ValueChanged, true);
            app.pEditField_2.Value = -1;
            app.vEditField_2Label = uilabel(app.GridLayout_2);
            app.vEditField_2Label.Text = 'v=';
            app.vEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.vEditField_2.ValueChangedFcn = createCallbackFcn(app, @vEditField_2ValueChanged, true);
            app.vEditField_2.Value = -1;
            app.hEditField_2Label = uilabel(app.GridLayout_2);
            app.hEditField_2Label.Text = 'h=';
            app.hEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.hEditField_2.ValueChangedFcn = createCallbackFcn(app, @hEditField_2ValueChanged, true);
            app.hEditField_2.Value = -1;
            app.uEditField_2Label = uilabel(app.GridLayout_2);
            app.uEditField_2Label.Text = 'u=';
            app.uEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.uEditField_2.ValueChangedFcn = createCallbackFcn(app, @uEditField_2ValueChanged, true);
            app.uEditField_2.Editable = 'off';
            app.uEditField_2.Value = -1;
            app.sEditField_2Label = uilabel(app.GridLayout_2);
            app.sEditField_2Label.Text = 's=';
            app.sEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.sEditField_2.ValueChangedFcn = createCallbackFcn(app, @sEditField_2ValueChanged, true);
            app.sEditField_2.Value = -1;
            app.xEditField_2Label = uilabel(app.GridLayout_2);
            app.xEditField_2Label.Text = 'x=';
            app.xEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.xEditField_2.Limits = [-1 1];
            app.xEditField_2.ValueChangedFcn = createCallbackFcn(app, @xEditField_2ValueChanged, true);
            app.xEditField_2.Value = -1;
            app.dd_d_p = uidropdown(app.GridLayout_2);
            app.dd_d_p.Items = {'Pa', 'kPa', 'MPa', 'bar'};
            app.dd_d_p.DropDownOpeningFcn = createCallbackFcn(app, @dd_d_pDropDownOpening, true);
            app.dd_d_p.ValueChangedFcn = createCallbackFcn(app, @dd_d_pValueChanged, true);
            app.dd_d_p.Value = 'bar';
            app.teplotaLabel_2 = uilabel(app.GridLayout_2);
            app.teplotaLabel_2.Text = 'teplota';
            app.tlakLabel_2 = uilabel(app.GridLayout_2);
            app.tlakLabel_2.Text = 'tlak';
            app.objemLabel_2 = uilabel(app.GridLayout_2);
            app.objemLabel_2.Text = 'objem';
            app.entalpieLabel_2 = uilabel(app.GridLayout_2);
            app.entalpieLabel_2.Text = 'entalpie';
            app.energieLabel_2 = uilabel(app.GridLayout_2);
            app.energieLabel_2.Text = 'energie';
            app.entropieLabel_2 = uilabel(app.GridLayout_2);
            app.entropieLabel_2.Text = 'entropie';
            app.suchostLabel_2 = uilabel(app.GridLayout_2);
            app.suchostLabel_2.Text = 'suchost';
            app.dd_d_t = uidropdown(app.GridLayout_2);
            app.dd_d_t.Items = {'°C', 'K'};
            app.dd_d_t.DropDownOpeningFcn = createCallbackFcn(app, @dd_d_tDropDownOpening, true);
            app.dd_d_t.ValueChangedFcn = createCallbackFcn(app, @dd_d_tValueChanged, true);
            app.dd_d_t.Value = '°C';
            app.dd_d_v = uidropdown(app.GridLayout_2);
            app.dd_d_v.Items = {'dm3/kg', 'm3/kg'};
            app.dd_d_v.DropDownOpeningFcn = createCallbackFcn(app, @dd_d_vDropDownOpening, true);
            app.dd_d_v.ValueChangedFcn = createCallbackFcn(app, @dd_d_vValueChanged, true);
            app.dd_d_v.Value = 'm3/kg';
            app.dd_d_h = uidropdown(app.GridLayout_2);
            app.dd_d_h.Items = {'J/kg', 'kJ/kg'};
            app.dd_d_h.DropDownOpeningFcn = createCallbackFcn(app, @dd_d_hDropDownOpening, true);
            app.dd_d_h.ValueChangedFcn = createCallbackFcn(app, @dd_d_hValueChanged, true);
            app.dd_d_h.Value = 'kJ/kg';
            app.dd_d_u = uidropdown(app.GridLayout_2);
            app.dd_d_u.Items = {'J/kg', 'kJ/kg'};
            app.dd_d_u.DropDownOpeningFcn = createCallbackFcn(app, @dd_d_uDropDownOpening, true);
            app.dd_d_u.ValueChangedFcn = createCallbackFcn(app, @dd_d_uValueChanged, true);
            app.dd_d_u.Value = 'kJ/kg';
            app.dd_d_s = uidropdown(app.GridLayout_2);
            app.dd_d_s.Items = {'J/kg/K', 'kJ/kg/K'};
            app.dd_d_s.DropDownOpeningFcn = createCallbackFcn(app, @dd_d_sDropDownOpening, true);
            app.dd_d_s.ValueChangedFcn = createCallbackFcn(app, @dd_d_sValueChanged, true);
            app.dd_d_s.Value = 'kJ/kg/K';
            app.dd_d_x = uidropdown(app.GridLayout_2);
            app.dd_d_x.Items = {'-'};
            app.dd_d_x.Value = '-';
            app.UloButton_2 = uibutton(app.Panel_6, 'push');
            app.UloButton_2.ButtonPushedFcn = createCallbackFcn(app, @UloButton_2Pushed, true);
            app.UloButton_2.Enable = 'off';
            app.UloButton_2.Text = {'Ulož'; ''};
            app.VypotiButton = uibutton(app.Panel_6, 'push');
            app.VypotiButton.ButtonPushedFcn = createCallbackFcn(app, @VypotiButtonPushed, true);
            app.VypotiButton.Enable = 'off';
            app.VypotiButton.Text = {'Vypočti'; ''};
            app.ZnovuButton = uibutton(app.Panel_6, 'push');
            app.ZnovuButton.ButtonPushedFcn = createCallbackFcn(app, @ZnovuButtonPushed, true);
            app.ZnovuButton.Enable = 'off';
            app.ZnovuButton.Text = 'Znovu';
            app.VymaButton = uibutton(app.VodnpraUIFigure, 'push');
            app.VymaButton.ButtonPushedFcn = createCallbackFcn(app, @VymaButtonPushed, true);
            app.VymaButton.Text = 'Reset';
            app.ExitButton = uibutton(app.VodnpraUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tag = 'testtag';
            app.ExitButton.Text = 'Exit';
            app.UloDiagramButton = uibutton(app.VodnpraUIFigure, 'push');
            app.UloDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @UloDiagramButtonPushed, true);
            app.UloDiagramButton.Text = 'Ulož Diagram';
            % Show the figure after all components are created
            app.VodnpraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Para_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VodnpraUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VodnpraUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VodnpraUIFigure)
        end
    end
end